classdef go_Adaptive_AEP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                       matlab.ui.Figure
        HardwareFunctionCheckPanel    matlab.ui.container.Panel
        bioamp_checkbox_6             matlab.ui.control.CheckBox
        bioamp_checkbox_5             matlab.ui.control.CheckBox
        bioamp_checkbox_4             matlab.ui.control.CheckBox
        bioamp_checkbox_3             matlab.ui.control.CheckBox
        bioamp_checkbox_2             matlab.ui.control.CheckBox
        bioamp_checkbox               matlab.ui.control.CheckBox
        HzLabel                       matlab.ui.control.Label
        text69_3                      matlab.ui.control.Label
        text69_2                      matlab.ui.control.Label
        minFreq_field                 matlab.ui.control.EditField
        maxFreq_field                 matlab.ui.control.EditField
        AveragesPanel                 matlab.ui.container.Panel
        pValTrackerFig1               matlab.ui.control.UIAxes
        pValTrackerFig2               matlab.ui.control.UIAxes
        pValTrackerFig3               matlab.ui.control.UIAxes
        pValTrackerFig4               matlab.ui.control.UIAxes
        fftAvgFig1                    matlab.ui.control.UIAxes
        fftAvgFig2                    matlab.ui.control.UIAxes
        fftAvgFig3                    matlab.ui.control.UIAxes
        fftAvgFig4                    matlab.ui.control.UIAxes
        wavAvgFig1                    matlab.ui.control.UIAxes
        wavAvgFig2                    matlab.ui.control.UIAxes
        wavAvgFig3                    matlab.ui.control.UIAxes
        wavAvgFig4                    matlab.ui.control.UIAxes
        ExperimentStatusPanel         matlab.ui.container.Panel
        TotaltrialsforcurrentstimulustypeLabel  matlab.ui.control.Label
        trialCounterALL               matlab.ui.control.Label
        TotaltrialsacrossallstimuliLabel  matlab.ui.control.Label
        trialCounterCurrentStim       matlab.ui.control.Label
        fftCounter                    matlab.ui.control.Label
        ofblockscollectedLabel        matlab.ui.control.Label
        AmplitudeTrackingPanel        matlab.ui.container.Panel
        doubleFreqTrackFig            matlab.ui.control.UIAxes
        stimFreqTrackFig              matlab.ui.control.UIAxes
        sixtyCycleTrackFig            matlab.ui.control.UIAxes
        LatencyStatusPanel            matlab.ui.container.Panel
        latencySampleLabel            matlab.ui.control.Label
        latencyThresholdLabel         matlab.ui.control.Label
        LatencySamplesLabel           matlab.ui.control.Label
        LatencyThresholdLabel         matlab.ui.control.Label
        CurrentstimulusPanel          matlab.ui.container.Panel
        dBLabel_2                     matlab.ui.control.Label
        currentAmplitudeLabel         matlab.ui.control.Label
        labelforcurrentfreq_2         matlab.ui.control.Label
        HzLabel_2                     matlab.ui.control.Label
        currentFreqLabel              matlab.ui.control.Label
        labelforcurrentfreq           matlab.ui.control.Label
        currentWaveformFig            matlab.ui.control.UIAxes
        FrequenciesPanel              matlab.ui.container.Panel
        frequenciesList               matlab.ui.control.Label
        uipanel1                      matlab.ui.container.Panel
        butt_stop                     matlab.ui.control.Button
        butt_pause                    matlab.ui.control.Button
        butt_go                       matlab.ui.control.Button
        butt_redo_calibration         matlab.ui.control.Button
        butt_go_calibrate             matlab.ui.control.Button
        AdaptiveParametersPanel       matlab.ui.container.Panel
        max_stim_presentation         matlab.ui.control.NumericEditField
        MaxStimulusPresentationLabel  matlab.ui.control.Label
        permutation_N_pval_min        matlab.ui.control.NumericEditField
        ofPermutationsLabel_2         matlab.ui.control.Label
        permutation_count             matlab.ui.control.NumericEditField
        ofPermutationsLabel           matlab.ui.control.Label
        SpectrogramParametersLabel    matlab.ui.control.Label
        FFTTTestParametersLabel       matlab.ui.control.Label
        alpha_total                   matlab.ui.control.NumericEditField
        TotalLabel                    matlab.ui.control.Label
        amplitude_step                matlab.ui.control.DropDown
        incrementby                   matlab.ui.control.Label
        can_start_spectro             matlab.ui.control.NumericEditField
        SpectroBlockSizeLabel_2       matlab.ui.control.Label
        spectro_block_size            matlab.ui.control.NumericEditField
        SpectroBlockSizeLabel         matlab.ui.control.Label
        fft_block_size                matlab.ui.control.NumericEditField
        FFTBlockSizeEditFieldLabel    matlab.ui.control.Label
        Image_2                       matlab.ui.control.Image
        Image                         matlab.ui.control.Image
        uipanel7                      matlab.ui.container.Panel
        AEP_scalefact                 matlab.ui.control.NumericEditField
        DownsamplingfactorLabel_2     matlab.ui.control.Label
        Downsample_Factor             matlab.ui.control.NumericEditField
        DownsamplingfactorLabel       matlab.ui.control.Label
        rejt                          matlab.ui.control.EditField
        SamplingFreq                  matlab.ui.control.NumericEditField
        SamplingFreqEditFieldLabel    matlab.ui.control.Label
        electrode_position            matlab.ui.control.DropDown
        txt_electrode_position        matlab.ui.control.Label
        check_confirm_hydrophone      matlab.ui.control.CheckBox
        bioamp_gain                   matlab.ui.control.DropDown
        text42                        matlab.ui.control.Label
        reject_artefacts              matlab.ui.control.CheckBox
        rtl                           matlab.ui.control.Label
        text55                        matlab.ui.control.Label
        nchans                        matlab.ui.control.DropDown
        text53                        matlab.ui.control.Label
        montage                       matlab.ui.control.EditField
        rejtLabel                     matlab.ui.control.Label
        push_artefact                 matlab.ui.control.Button
        disp_rate                     matlab.ui.control.Label
        text44_2                      matlab.ui.control.Label
        text43_2                      matlab.ui.control.Label
        text38                        matlab.ui.control.Label
        uipanel2                      matlab.ui.container.Panel
        disp_file_savename            matlab.ui.control.Label
        txt_otolith_condition         matlab.ui.control.Label
        edit_fish_ID                  matlab.ui.control.EditField
        edit_run_number               matlab.ui.control.EditField
        text11                        matlab.ui.control.Label
        pup_otolith_condition         matlab.ui.control.DropDown
        text9                         matlab.ui.control.Label
        pup_experiment                matlab.ui.control.DropDown
        text6                         matlab.ui.control.Label
        uipanel3                      matlab.ui.container.Panel
        randomizeCheckBox             matlab.ui.control.CheckBox
        minAmplitude                  matlab.ui.control.NumericEditField
        MinLabel                      matlab.ui.control.Label
        maxAmplitudes                 matlab.ui.control.EditField
        MaxLabel                      matlab.ui.control.Label
        freqRange                     matlab.ui.control.EditField
        HzEditFieldLabel              matlab.ui.control.Label
        DurationLabel                 matlab.ui.control.Label
        FrequenciesLabel              matlab.ui.control.Label
        msLabel                       matlab.ui.control.Label
        totalDurationVal              matlab.ui.control.Label
        TotaldurationLabel            matlab.ui.control.Label
        AmplitudedBLabel              matlab.ui.control.Label
        stim_ramp_proportion          matlab.ui.control.NumericEditField
        RampproportionLabel           matlab.ui.control.Label
        stim_dur_fullamp              matlab.ui.control.NumericEditField
        FullamplitudeportiondurationmsEditFieldLabel  matlab.ui.control.Label
        goadaptiveaepLabel            matlab.ui.control.Label
    end


    methods (Access = private)
        function edit8_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit_ntrials (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function edit_click_duration_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit_click_duration (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function pup_click_duration_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to pup_click_duration (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: popupmenu controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function pup_tone_duration_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to pup_tone_duration (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: popupmenu controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function pup_tone_freq_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to pup_tone_freq (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: popupmenu controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end


 %#%#%#%#%#%# CREATE h STRUCTURE %#%#%#%#%#%#%#%#%#%#%#%#%
 function h = read_common_vars(app, handles)
            % function h = read_common_vars(handles)
            %%% This function reads all of the handles in the GUI, loads into structure
            %%% called 'h' where they are easily accessible

            % Experiment params
            h.fish_ID = app.edit_fish_ID.Value;
            h.experiment = app.pup_experiment.Value;
            h.otolith_condition = app.pup_otolith_condition.Value;
            h.run = app.edit_run_number.Value;
 
            % Stimulus params
            %             h.min_freq =  str2double(app.minFreq_field.Value);
            %             h.max_freq =  str2double(app.maxFreq_field.Value);
            freqString = app.freqRange.Value;
            h.freqRange = str2double(split(freqString, ','));
            h.stim_dur_fullamp  = app.stim_dur_fullamp.Value;
            h.stim_ramp_proportion = app.stim_ramp_proportion.Value;
            h.randomizeCheckBox = app.randomizeCheckBox.Value;
            
            % Parse frequency-specific max amplitudes
            maxAmpString = app.maxAmplitudes.Value;
            h.maxAmplitudes = str2double(split(maxAmpString, ','));
            h.minAmplitude = app.minAmplitude.Value;

            % Validate that max amplitudes match frequency count
            if length(h.maxAmplitudes) ~= length(h.freqRange)
                error('Number of max amplitudes (%d) must match number of frequencies (%d)', ...
                    length(h.maxAmplitudes), length(h.freqRange));
            end

            % Validate that all max amplitudes are greater than min amplitude
            if any(h.maxAmplitudes <= h.minAmplitude)
                error('All max amplitudes must be greater than min amplitude (%.1f dB)', h.minAmplitude);
            end

            h.minAmplitude = app.minAmplitude.Value;

            % Recording Params
            h.nchans            = str2double(app.nchans.Value);
            h.montage           = app.montage.Value;  % Assuming this is a dropdown or text
            h.electrode_position = app.electrode_position.Value;  % Same as above
            h.bioamp_gain = str2double(app.bioamp_gain.Value(1:end-1));
            h.artrej            = str2double(app.rejt.Value);
            
            h.sampfreq = app.SamplingFreq.Value; %#%#
            h.downsample_factor = app.Downsample_Factor.Value; %#%#
            h.downsampled_fs = h.sampfreq / h.downsample_factor; %#%#

            h.AEP_scalefact =  app.AEP_scalefact.Value; %#%#%# 

            % Adaptive Params
            h.fft_block_size = app.fft_block_size.Value;
            h.spectro_block_size = app.spectro_block_size.Value;
            h.can_start_spectro = app.can_start_spectro.Value;
            h.amplitude_step = str2double(app.amplitude_step.Value(1:end-3)); % Extract number from "X dB"
            h.db_increment = h.amplitude_step;
            h.permutation_count = app.permutation_count.Value;
            h.permutation_N_pval_min = app.permutation_N_pval_min.Value;
            h.max_stim_presentation = app.max_stim_presentation.Value;
            h.alpha_total = app.alpha_total.Value;
            h.db_increment = str2double(app.amplitude_step.Value(1:end-3));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function go_Fish_AEP_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to go_Fish_AEP (see VARARGIN)

            % Choose default command line output for go_Fish_AEP
            handles.output = hObject;

            % Update handles structure
            guidata(hObject, handles);
            
        end

        % Button pushed function: butt_go
        function butt_go_Callback(app, event)

%#%#%#%%#%#%#%#%#%#% WHAT HAPPENS WHEN YOU PRESS "START EXPERIMENT!" %#%#%#%%#%#%#%#%#%#%

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            global calibration_data

            %% First check to make sure that all necessary pre-test steps have been completed
            h = read_common_vars(app, handles);
            
            if strcmp(h.fish_ID(end),'X')
                errordlg('Please enter a valid Fish ID','Enter Fish ID!');
                error('Please enter a valid Fish ID');
            end
            if isempty(h.run)
                errordlg('Please enter a test description (e.g., "1")');
                error('Please enter a test description (e.g., "1")');
            end
            if strcmp(app.butt_go_calibrate.Text,'C A L I B R A T E')
                errordlg('You must calibrate (or load a calibration file) before beginning');
                error('You must calibrate (or load a calibration file) before beginning');
            end
            if app.bioamp_checkbox.Value ~=1
                errordlg('You must confirm that the bioamplifier gain is set at the correct level. (The pull-down menu gain should match the "GAIN" dial on the bioamp.)');
                error('You must confirm that the bioamplifier gain is set at the correct level. (The pull-down menu gain should match the "GAIN" dial on the bioamp.)');
            end
            if app.check_confirm_hydrophone.Value ~=1
                errordlg('You must confirm that the hydrophone amp is set to output 100 mV/Pa - many calculations depend on it!');
                error('You must confirm that the hydrophone amp is set to output 100 mV/Pa - many calculations depend on it!');
            end

            % set up stim and tracker objects
            exp_params = struct(...
                'fish_ID', h.fish_ID, ...
                'experiment', h.experiment, ...
                'otolith_condition', h.otolith_condition, ...
                'run', h.run...
                );

            stim_params = struct(...
                'freqRange', h.freqRange, ...
                'db_increment', h.db_increment, ...
                'stim_dur_fullamp', h.stim_dur_fullamp, ...
                'stim_ramp_proportion', h.stim_ramp_proportion, ...
                'maxAmplitude', h.maxAmplitudes, ...
                'minAmplitude', h.minAmplitude,...
                'randomizeCheckBox', h.randomizeCheckBox ...
                );
            
            %                 'min_freq', h.min_freq, ...
            %                 'max_freq', h.max_freq, ...

            rec_params = struct(...
                'nchans', h.nchans, ...
                'montage', h.montage, ...
                'electrode_position', h.electrode_position, ...
                'bioamp_gain', h.bioamp_gain, ...
                'sampfreq',h.sampfreq,...
                'artrej', h.artrej, ...
                'AEP_scalefact', h.AEP_scalefact,...
                'downsample_factor', h.downsample_factor,...
                'downsampled_fs', h.downsampled_fs...
                );

            adapt_params = struct(...
                'fft_block_size', h.fft_block_size, ...
                'spectro_block_size', h.spectro_block_size, ...
                'can_start_spectro', h.can_start_spectro, ...
                'amplitude_step', h.amplitude_step, ...
                'permutation_count', h.permutation_count, ...
                'permutation_N_pval_min', h.permutation_N_pval_min, ...
                'max_stim_presentation', h.max_stim_presentation, ...
                'alpha_total', h.alpha_total...
                );

            track_params = [];
            
            mysavename = sprintf('%s_%s_%s_ts_run%s.mat',...
                h.fish_ID,h.experiment,h.run);

            % update file savename and time remaining fields
            app.disp_file_savename.Text = mysavename;

            % load params into argument struct
            args = struct('exp_params',exp_params,...
                'stim_params',stim_params,...
                'rec_params',rec_params,...
                'track_params',track_params,...
                'adapt_params', adapt_params, ...
                'calibration',calibration_data);
            
            % pause just a moment before beginning;
            drawnow;pause(0.1)
            
            % Call the main experiment file
            fprintf('Experiment beginning now...\n');
            
            %% SEND IT! %%
            run_adaptiveAEP_fish(args,app);
            
            %%% Done ! %%%
            fprintf('Yippe! All done !\n'); 
            [y,fs] = audioread('familymartjingle.mp3');
            sound(y,fs)


%#%#%#%#%#%#%#%#%#%#% CODE GRAVEYARD #%#%#%#%#%#%#%#%#%#%#%#%#%#%
    
    
%             tone_intensity_temp = get(handles.pup_tone_intensity,'string');
%             tone_intensity_str = tone_intensity_temp{get(handles.pup_tone_intensity,'value')};
%             h.tone_intensity = str2num(tone_intensity_str(1:3));
%             h.tone_duration = str2num(get(handles.edit_tone_duration,'string'));
%             h.isi = str2num(get(handles.edit_isi,'string'));
%             h.tone_frequency = str2num(get(handles.edit_tone_frequency,'string'));
%             click_intensity_temp = get(handles.pup_click_intensity,'string');
%             click_intensity_str = click_intensity_temp{get(handles.pup_click_intensity,'value')};
%             h.click_intensity = str2num(click_intensity_str(1:3));
%             angle_temp = get(handles.pup_angle,'string');
%             angle_str = angle_temp{get(handles.pup_angle,'value')};
%             h.angle_az = str2num(angle_str(1));
%             h.angle_el = str2num(angle_str(3));
% 
%             No longer using%
%             experiment_temp = get(handles.pup_experiment,'string');
%             h.experiment = experiment_temp{get(handles.pup_experiment,'value')};
%             electrode_position_temp = get(handles.pup_electrode_position,'string');
%             electrode_position_temp = get(handles.pup_electrode_position,'string');
%             h.electrode_position = electrode_position_temp{get(handles.pup_electrode_position,'value')};
%             otolith_condition_temp = get(handles.pup_otolith_condition,'string');
%             h.otolith_condition = otolith_condition_temp{get(handles.pup_otolith_condition,'value')};
% 
%             % Get number of trials
%             h.ntrials = str2num(get(handles.edit_ntrials,'string'));
% 
%             % Get bioamp gain
%             bioamp_gain_temp = get(handles.pup_bioamp_gain,'string');
%             h.bioamp_gain = bioamp_gain_temp{get(handles.pup_bioamp_gain,'value')};
% 
%             % Are we acquiring accelerometer data?
%             if get(handles.radio_accelerometer_Y,'Value') == 1
%                 h.acquire_accel = 1;
%             else
%                 h.acquire_accel = 0;
%             end

            % 
%             if strcmp(h.experiment,'Stereo AM')
%                 stim_params = struct('experiment',h.experiment,...
%                     'tone_level',h.tone_intensity,...
%                     'tone_frequency',h.tone_frequency,... %%%%%%%%%%%% set frequency here
%                     'AMrate',[10 20],... %%%%%%%%%%%% set AM rate here
%                     'tone_duration',h.tone_duration,...
%                     'click_intensity',h.click_intensity,...
%                     'angle_az',h.angle_az,...
%                     'angle_el',h.angle_el,...
%                     'isi',h.isi);
%             elseif strcmp(h.experiment,'fishclicks')
%                 stim_params = struct('experiment',h.experiment,...
%                     'tone_level',h.tone_intensity,...
%                     'tone_frequency',h.tone_frequency,...
%                     'tone_duration',h.tone_duration,...
%                     'click_intensity',h.click_intensity,...
%                     'angle_az',h.angle_az,...
%                     'angle_el',h.angle_el,...
%                     'isi',h.isi);
%             else
%                 stim_params = struct('experiment',h.experiment,...
%                     'tone_level',h.tone_intensity,...
%                     'tone_frequency',h.tone_frequency,...
%                     'tone_duration',h.tone_duration,...
%                     'click_intensity',h.click_intensity,...
%                     'angle_az',h.angle_az,...
%                     'angle_el',h.angle_el,...
%                     'isi',h.isi);
%             end
%             rec_params = struct('ntrials',h.ntrials,...
%                 'tone_level',h.tone_intensity,...
%                 'bioamp_gain',h.bioamp_gain,...
%                 'acquire_accel',h.acquire_accel);

%             % We will track frequency unless we are measuring click AEPs...
%             if strcmp(stim_params.experiment,'Click AEP')
%                 track_params = [];
%                 mysavename = sprintf('%s_%s_%s_ts_run%s.mat',...
%                     h.fish_ID,h.experiment,num2str(h.tone_intensity),h.run);
%             elseif strcmp(stim_params.experiment,'fishclicks')
%                 track_params = [];
%                 mysavename = sprintf('%s_%s_%s_ts_run%s_%s.mat',...
%                     h.fish_ID,h.experiment,num2str(h.tone_intensity),h.run,calibration_data.fname);
%             elseif strcmp(stim_params.experiment,'Directional Sensitivity')
%                 track_params = struct('tracked_vars',struct('frequency',h.tone_frequency),'numtrials_perstim',h.ntrials,'shuf_rule','full');
%                 mysavename = sprintf('%s_%s_%s_run%s.mat',...
%                     h.fish_ID,h.experiment,h.otolith_condition,h.run);
%             else
%                 track_params = struct('tracked_vars',struct('frequency',h.tone_frequency),'numtrials_perstim',h.ntrials,'shuf_rule','full');
%                 mysavename = sprintf('%s_%s_%s_ts_run%s.mat',...
%                     h.fish_ID,h.experiment,num2str(h.click_intensity),h.run);
%             end
        end

        % Button pushed function: butt_go_calibrate
        function butt_go_calibrate_Callback(app, event)
            addpath(genpath('C:\Users\AEP\Desktop\Experiments\adaptiveAEP_2025'))
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            %% ensure that hydrophone amp is set properly
            if get(handles.check_confirm_hydrophone,'Value')~=1
                errordlg('You must first confirm that the hydrophone amp is set to output 100 mV/Pa - many calculations depend on it!');
                error('You must first confirm that the hydrophone amp is set to output 100 mV/Pa - many calculations depend on it!');
                success = 0;
            end
            %% Also ensure that Fish ID has been entered so that calibration data may be saved appropriately...
            h = read_common_vars(app, handles);
            
            if strcmp(h.fish_ID(end),'X')
                errordlg('Please enter a valid Fish ID','Enter Fish ID');
                error('Please enter a valid Fish ID');
                success = 0;
            else
                success=1;
            end
            
%             %% Just check if there is anything missing...
%             any_nan = any(structfun(@(x) isnumeric(x) && any(isnan(x)),h));          
%             if any_nan
%                 errordlg('There is missing information in the GUI, ensure all information is filled prior to calibration','Check GUI Inputs')
%                 error('Please enter missing info')
%                 success= 0;
%             else
%                 success=1;
%             end
            
            %% If so, then launch calibration GUI with the following data:
            global calibration_data
            global ghandles
            
            if success   
                ghandles = guihandles;
                set(handles.butt_go_calibrate,'String','Working...');
                set(handles.butt_go_calibrate,'BackgroundColor','y');
%                 set(handles.butt_go_calibrate,'Enable','off');
                
                % call calibration function
                calibration_data.experiment = h.experiment;
                    calibration_data.useclicks = 0;
                    freqString = app.freqRange.Value;
                    freq = str2double(split(freqString, ','));
                    for ifreq = 1:length(freq)
                        durs(ifreq) = 400; % convert to milliseconds
                    end
                    calibration_data.frequency_range = freq;
                    calibration_data.stimulus_duration = durs;
                    calibration_data.target_level = 130; %%% set target calibration level here
                    calibrate(app);
            end
     
            
           %#%#%#%#%#%#% CODE GRAVEYARD %!%!%!%!%!%!%!%!%!%!%%!
%                 call calibration function
%                 if strcmp(h.experiment,'fishclicks')
%                     cwd = cd;
%                     cd('C:\Users\AEP\Desktop\Experiments\fishclicks\clicks');
%                     [fname,pname] = uigetfile('*.mat','Pick a click waveform');
%                     calibration_data.clickdata = load(fullfile(pname,fname));
%                     cd(cwd);
%                     calibration_data.success = 1;
%                     calibration_data.fname = fname;
%                 elseif strcmp(h.experiment,'Click AEP')
%                     calibration_data.experiment = h.experiment;
%                     calibration_data.useclicks = 1;
%                     calibration_data.frequency_range = h.tone_frequency;
%                     calibration_data.stimulus_duration = h.tone_duration;
%                     calibration_data.target_level = 130; %%% set target calibration level here
%                     calibrate;
%                 elseif strcmp(h.experiment,'Stereo AM')
%                     calibration_data.experiment = h.experiment;
%                     calibration_data.useclicks = 0;
%                     calibration_data.frequency_range = h.tone_frequency;
%                     calibration_data.stimulus_duration = h.tone_duration;
%                     calibration_data.target_level = 130; %%% set target calibration level here
%                     calibrate;
%                 else
%                     calibration_data.experiment = h.experiment;
%                     calibration_data.useclicks = 0;
%                     calibration_data.frequency_range = h.tone_frequency;
%                     calibration_data.stimulus_duration = h.tone_duration;
%                     calibration_data.target_level = 130; %%% set target calibration level here
%                     calibrate;
%                 end
        end

        % Button pushed function: butt_pause
        function butt_pause_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            %%%%%%
            %%%%%% Do something here to make the pause button functional...
            disp('The pause button does not work yet... sorry. :(');
        end

        % Button pushed function: butt_redo_calibration
        function butt_redo_calibration_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            global calibration_data

            set(handles.butt_go_calibrate,'Backgroundcolor','g');
            set(handles.butt_go_calibrate,'string','CALIBRATE!');
            set(handles.butt_go_calibrate','Enable','on');
            calibration_data = [];
            warndlg('All calibiration data has been cleared. Recalibrate to begin experiment');
        end

        % Value changed function: pup_experiment
        function pup_experiment_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>


% % Autofills stimulus parameters
%             h = read_common_vars(app, handles);
%             switch h.experiment
%                 case 'Electrode Position'
%                     % Set experiment-specific parameters
%                     set(handles.edit_tone_duration,'string','0.100');
%                     set(handles.edit_isi,'string','0.300');
%                     set(handles.edit_tone_frequency,'string','100 200 300');
%                     set(handles.edit_ntrials,'string','1000');
%                     % Make sure relevant controls are active
%                     set(handles.txt_electrode_position','Enable','on');
%                     set(handles.pup_electrode_position','Enable','on');
%                     set(handles.txt_tone_duration,'Enable','on');
%                     set(handles.edit_tone_duration,'Enable','on');
%                     set(handles.txt_tone_frequency,'Enable','on');
%                     set(handles.edit_tone_frequency,'Enable','on');
%                     set(handles.txt_tone_intensity,'Enable','on');
%                     set(handles.pup_tone_intensity,'Enable','on');
%                     % ...and that irrelevant controls are inactive
%                     set(handles.txt_otolith_condition','Enable','off');
%                     set(handles.pup_otolith_condition','Enable','off');
%                     set(handles.txt_angle','Enable','off');
%                     set(handles.pup_angle','Enable','off');
%                     set(handles.txt_click_intensity,'Enable','off');
%                     set(handles.pup_click_intensity,'Enable','off');
%                 case 'Directional Sensitivity'
%                     % Set experiment-specific parameters
%                     set(handles.edit_tone_duration,'string','0.75');
%                     set(handles.edit_isi,'string','0.9');
%                     set(handles.edit_tone_frequency,'string','085');
%                     set(handles.edit_ntrials,'string','500');
%                     % Make sure relevant controls are active
%                     set(handles.txt_otolith_condition','Enable','on');
%                     set(handles.pup_otolith_condition','Enable','on');
%                     set(handles.txt_angle','Enable','on');
%                     set(handles.pup_angle','Enable','on');
%                     set(handles.txt_tone_duration,'Enable','on');
%                     set(handles.edit_tone_duration,'Enable','on');
%                     set(handles.txt_tone_frequency,'Enable','on');
%                     set(handles.edit_tone_frequency,'Enable','on');
%                     set(handles.txt_tone_intensity,'Enable','on');
%                     set(handles.pup_tone_intensity,'Enable','on');
%                     % ...and that irrelevant controls are inactive
%                     set(handles.txt_electrode_position','Enable','off');
%                     set(handles.pup_electrode_position','Enable','off');
%                     set(handles.txt_click_intensity,'Enable','off');
%                     set(handles.pup_click_intensity,'Enable','off');
%                 case 'AEP Audiogram'
%                     % Set experiment-specific parameters
%                     set(handles.edit_tone_duration,'string','0.2');
%                     set(handles.edit_isi,'string','0.5');
%                     set(handles.edit_tone_frequency,'string','105 205 305 405');
%                     set(handles.edit_ntrials,'string','1000');
%                     % Make sure relevant controls are active
%                     set(handles.txt_tone_duration,'Enable','on');
%                     set(handles.edit_tone_duration,'Enable','on');
%                     set(handles.txt_tone_frequency,'Enable','on');
%                     set(handles.edit_tone_frequency,'Enable','on');
%                     set(handles.txt_tone_intensity,'Enable','on');
%                     set(handles.pup_tone_intensity,'Enable','on');
%                     % ...and that irrelevant controls are inactive
%                     set(handles.txt_electrode_position','Enable','off');
%                     set(handles.pup_electrode_position','Enable','off');
%                     set(handles.txt_otolith_condition','Enable','off');
%                     set(handles.pup_otolith_condition','Enable','off');
%                     set(handles.txt_angle','Enable','off');
%                     set(handles.pup_angle','Enable','off');
%                     set(handles.txt_click_intensity,'Enable','off');
%                     set(handles.pup_click_intensity,'Enable','off');
%                 case 'Click AEP'
%                     set(handles.edit_isi,'string','0.20');
%                     set(handles.edit_ntrials,'string','3000');
%                     set(handles.edit_tone_frequency,'string','0');
%                     % Make sure that click controls are active
%                     set(handles.txt_click_intensity,'Enable','on');
%                     set(handles.pup_click_intensity,'Enable','on');
%                     % deactivate tone controls
%                     set(handles.txt_tone_duration,'Enable','off');
%                     set(handles.edit_tone_duration,'Enable','off');
%                     set(handles.txt_tone_frequency,'Enable','off');
%                     set(handles.edit_tone_frequency,'Enable','off');
%                     set(handles.txt_tone_intensity,'Enable','off');
%                     set(handles.pup_tone_intensity,'Enable','off');
%                 case 'fishclicks'
%                     set(handles.edit_isi,'string','0');
%                     set(handles.edit_ntrials,'string','3000');
%                     set(handles.edit_tone_frequency,'string','0');
%                     % Make sure that click controls are inactive... wav file defines
%                     % this
%                     set(handles.txt_click_intensity,'Enable','off');
%                     set(handles.pup_click_intensity,'Enable','off');
%                     % deactivate tone controls
%                     set(handles.txt_tone_duration,'Enable','off');
%                     set(handles.edit_tone_duration,'Enable','off');
%                     set(handles.txt_tone_frequency,'Enable','off');
%                     set(handles.edit_tone_frequency,'Enable','off');
%                     set(handles.txt_tone_intensity,'Enable','off');
%                     set(handles.pup_tone_intensity,'Enable','off');
%                 case 'Stereo AM'
%                     set(handles.edit_tone_duration,'string','2.0');
%                     set(handles.edit_isi,'string','2.3');
%                     set(handles.edit_ntrials,'string','100');
%                     set(handles.edit_tone_frequency,'string','105');
%                     % Make sure that click controls are active
%                     set(handles.txt_click_intensity,'Enable','off');
%                     set(handles.pup_click_intensity,'Enable','off');
%                     % deactivate tone controls
%                     set(handles.txt_tone_duration,'Enable','on');
%                     set(handles.edit_tone_duration,'Enable','on');
%                     set(handles.txt_tone_frequency,'Enable','on');
%                     set(handles.edit_tone_frequency,'Enable','on');
%                     set(handles.txt_tone_intensity,'Enable','off');
%                     set(handles.pup_tone_intensity,'Enable','off');
%             end
        end

        % Callback function
        function maxFreq_fieldValueChanged(app, event)
           
        end

        % Callback function
        function minFreq_fieldValueChanged(app, event)
            if isempty(app.minFreq_field.Value)
                % Do nothing until both are filled
            else
                [freqset, durs, ISI] = one3rd_octavefier(str2double(app.maxFreq_field.Value),str2double(app.minFreq_field.Value));
                app.freq_set.Value = sprintf('%1.0f,',freqset); % Comma list
                app.freq_set.Value = app.freq_set.Value(1:end-1);
                app.dur_set.Value = sprintf('%5.2f,',durs);
                app.dur_set.Value = app.dur_set.Value(1:end-1);
                app.isi.Value = sprintf('%1.2f',ISI);
            end
        end

        % Button pushed function: push_artefact
        function push_artefact_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_artefact (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if playrec('isInitialised'),
                warndlg(sprintf(['Playrec is currently in use. If no protocol is actively '...
                    'running\n enter into the command window "playrec(''reset'')" '...
                    'and attempt artefact rejection once more.\n']));
            else
                % Some parameters to be used
                Fs = 44100; sec = 10;
                amptemp = get(handles.pup_amp_gain,'string');
                amp_gain = amptemp{get(handles.pup_amp_gain,'value')};
                amp_corr = str2double(amp_gain(1:end-1)) * 0.1972 * 0.8 * 1e-6;
            
                % Record AEP trace
                InitFireFace(Fs); InitFireFace(Fs);
                apage = playrec('rec', sec*Fs, 8);
                playrec('block', apage);
                arec = double(playrec('getRec',apage))./amp_corr;
                playrec('delPage',apage); playrec('reset');
            
                % Plot AEP trace,
                divs = 50; t = linspace(0,sec/divs,sec*Fs/divs)'.*1000;
                scrsz = get(0,'ScreenSize');
                af = figure('Name', 'Artefact Rejection Plot',...
                    'Position',[1 scrsz(4)/2.5 scrsz(3)/2 scrsz(4)/2]);
                set(af, 'CurrentAxes', axes());
                aaf = findall(af,'type','axes');
                plot(aaf, repmat(t,1,divs),reshape(arec,[],divs));
                ylabel('AEP voltage (\muV)', 'fontsize', 14, 'fontweight', 'bold');
                xlabel('Time (ms)', 'fontsize', 14, 'fontweight', 'bold');
                [~,thresh] = ginput(1); close(af);
                set(handles.rejt,'String', sprintf('%.2f', thresh));
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'go_Fish_AEP';
            app.figure1.Tag = 'figure1';
            app.goadaptiveaepLabel = uilabel(app.figure1);
            app.goadaptiveaepLabel.Text = 'go adaptive aep!';
            app.uipanel3 = uipanel(app.figure1);
            app.uipanel3.Title = '2. Stimulus Parameters';
            app.uipanel3.Tag = 'uipanel3';
            app.FullamplitudeportiondurationmsEditFieldLabel = uilabel(app.uipanel3);
            app.FullamplitudeportiondurationmsEditFieldLabel.Text = 'Full amplitude portion duration (ms)';
            app.stim_dur_fullamp = uieditfield(app.uipanel3, 'numeric');
            app.stim_dur_fullamp.Value = 100;
            app.RampproportionLabel = uilabel(app.uipanel3);
            app.RampproportionLabel.Text = 'Ramp proportion';
            app.stim_ramp_proportion = uieditfield(app.uipanel3, 'numeric');
            app.stim_ramp_proportion.Value = 0.3;
            app.AmplitudedBLabel = uilabel(app.uipanel3);
            app.AmplitudedBLabel.Text = 'Amplitude (dB):';
            app.TotaldurationLabel = uilabel(app.uipanel3);
            app.TotaldurationLabel.Text = 'Total duration:';
            app.totalDurationVal = uilabel(app.uipanel3);
            app.totalDurationVal.Text = 'XXXX';
            app.msLabel = uilabel(app.uipanel3);
            app.msLabel.Text = 'ms';
            app.FrequenciesLabel = uilabel(app.uipanel3);
            app.FrequenciesLabel.Text = 'Frequencies:';
            app.DurationLabel = uilabel(app.uipanel3);
            app.DurationLabel.Text = 'Duration: ';
            app.HzEditFieldLabel = uilabel(app.uipanel3);
            app.HzEditFieldLabel.Text = 'Hz';
            app.freqRange = uieditfield(app.uipanel3, 'text');
            app.freqRange.Value = '55,100,440,880,1695';
            app.MaxLabel = uilabel(app.uipanel3);
            app.MaxLabel.Text = 'Max';
            app.maxAmplitudes = uieditfield(app.uipanel3, 'text');
            app.maxAmplitudes.Value = '115,115,150,150.150';
            app.MinLabel = uilabel(app.uipanel3);
            app.MinLabel.Text = 'Min';
            app.minAmplitude = uieditfield(app.uipanel3, 'numeric');
            app.randomizeCheckBox = uicheckbox(app.uipanel3);
            app.randomizeCheckBox.Text = 'Randomize order?';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = '1. Experiment Parameters';
            app.uipanel2.Tag = 'uipanel2';
            app.text6 = uilabel(app.uipanel2);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Fish ID:';
            app.pup_experiment = uidropdown(app.uipanel2);
            app.pup_experiment.Items = {'AdaptiveAEP_Ratfish', 'AdaptiveAEP_Midshipman'};
            app.pup_experiment.ValueChangedFcn = createCallbackFcn(app, @pup_experiment_Callback, true);
            app.pup_experiment.Tag = 'pup_experiment';
            app.pup_experiment.Value = 'AdaptiveAEP_Ratfish';
            app.text9 = uilabel(app.uipanel2);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Experiment';
            app.pup_otolith_condition = uidropdown(app.uipanel2);
            app.pup_otolith_condition.Items = {'Normal', 'Left Removed', 'Right Removed', 'Both Removed'};
            app.pup_otolith_condition.Tag = 'pup_otolith_condition';
            app.pup_otolith_condition.Enable = 'off';
            app.pup_otolith_condition.Value = 'Normal';
            app.text11 = uilabel(app.uipanel2);
            app.text11.Tag = 'text11';
            app.text11.Text = 'Run #:';
            app.edit_run_number = uieditfield(app.uipanel2, 'text');
            app.edit_run_number.Tag = 'edit_run_number';
            app.edit_run_number.Value = '1';
            app.edit_fish_ID = uieditfield(app.uipanel2, 'text');
            app.edit_fish_ID.Tag = 'edit_fish_ID';
            app.edit_fish_ID.Value = 'hydrolagusColliei_X';
            app.txt_otolith_condition = uilabel(app.uipanel2);
            app.txt_otolith_condition.Tag = 'txt_otolith_condition';
            app.txt_otolith_condition.Text = 'Otolith Cond. :';
            app.disp_file_savename = uilabel(app.uipanel2);
            app.disp_file_savename.Tag = 'disp_file_savename';
            app.disp_file_savename.Text = 'file name will be listed here...';
            app.uipanel7 = uipanel(app.figure1);
            app.uipanel7.Title = '3. Recording Parameters';
            app.uipanel7.Tag = 'uipanel7';
            app.text38 = uilabel(app.uipanel7);
            app.text38.Tag = 'text38';
            app.text38.Text = 'Montage:';
            app.text43_2 = uilabel(app.uipanel7);
            app.text43_2.Tag = 'text43';
            app.text43_2.Text = 'Rate:';
            app.text44_2 = uilabel(app.uipanel7);
            app.text44_2.Tag = 'text44';
            app.text44_2.Text = 'Hz';
            app.disp_rate = uilabel(app.uipanel7);
            app.disp_rate.Tag = 'disp_rate';
            app.disp_rate.Text = 'XXX';
            app.push_artefact = uibutton(app.uipanel7, 'push');
            app.push_artefact.ButtonPushedFcn = createCallbackFcn(app, @push_artefact_Callback, true);
            app.push_artefact.Tag = 'push_artefact';
            app.push_artefact.Text = {'ARTIFACT REJECTION'; ''};
            app.rejtLabel = uilabel(app.uipanel7);
            app.rejtLabel.Tag = 'rejtLabel';
            app.rejtLabel.Text = 'Rej. thresh (uV):';
            app.montage = uieditfield(app.uipanel7, 'text');
            app.montage.Tag = 'pup_montage';
            app.montage.Value = 'EARS';
            app.text53 = uilabel(app.uipanel7);
            app.text53.Tag = 'text53';
            app.text53.Text = 'N Channels';
            app.nchans = uidropdown(app.uipanel7);
            app.nchans.Items = {'1', '2', '3', '4'};
            app.nchans.Tag = 'pup_nchans';
            app.nchans.Value = '4';
            app.text55 = uilabel(app.uipanel7);
            app.text55.Tag = 'text55';
            app.text55.Text = 'Rej. Count:';
            app.rtl = uilabel(app.uipanel7);
            app.rtl.Tag = 'rtl';
            app.rtl.Text = 'N/A';
            app.reject_artefacts = uicheckbox(app.uipanel7);
            app.reject_artefacts.Tag = 'reject_artefacts';
            app.reject_artefacts.Text = 'Enable rejection';
            app.text42 = uilabel(app.uipanel7);
            app.text42.Tag = 'text42';
            app.text42.Text = 'Bioamp. gain:';
            app.bioamp_gain = uidropdown(app.uipanel7);
            app.bioamp_gain.Items = {'10000X', '20000X', '50000X'};
            app.bioamp_gain.Tag = 'pup_bioamp_gain';
            app.bioamp_gain.Value = '10000X';
            app.check_confirm_hydrophone = uicheckbox(app.uipanel7);
            app.check_confirm_hydrophone.Tag = 'check_confirm_hydrophone';
            app.check_confirm_hydrophone.Text = 'Confirm hydrophone amp set to 100mV/Pa';
            app.txt_electrode_position = uilabel(app.uipanel7);
            app.txt_electrode_position.Tag = 'txt_electrode_position';
            app.txt_electrode_position.Text = 'Electrode Posi:';
            app.electrode_position = uidropdown(app.uipanel7);
            app.electrode_position.Items = {'1', '2', '3'};
            app.electrode_position.Tag = 'pup_electrode_position';
            app.electrode_position.Value = '1';
            app.SamplingFreqEditFieldLabel = uilabel(app.uipanel7);
            app.SamplingFreqEditFieldLabel.Text = 'Sampling Freq.';
            app.SamplingFreq = uieditfield(app.uipanel7, 'numeric');
            app.SamplingFreq.ValueDisplayFormat = '%5.0f';
            app.SamplingFreq.Enable = 'off';
            app.SamplingFreq.Value = 44100;
            app.rejt = uieditfield(app.uipanel7, 'text');
            app.rejt.Tag = 'rejt';
            app.rejt.Value = '20';
            app.DownsamplingfactorLabel = uilabel(app.uipanel7);
            app.DownsamplingfactorLabel.Text = 'Downsampling factor';
            app.Downsample_Factor = uieditfield(app.uipanel7, 'numeric');
            app.Downsample_Factor.ValueDisplayFormat = '%5.0f';
            app.Downsample_Factor.Editable = 'off';
            app.Downsample_Factor.Value = 2;
            app.DownsamplingfactorLabel_2 = uilabel(app.uipanel7);
            app.DownsamplingfactorLabel_2.Text = 'Voltage Scaling Factor';
            app.AEP_scalefact = uieditfield(app.uipanel7, 'numeric');
            app.AEP_scalefact.ValueDisplayFormat = '%5.3f';
            app.AEP_scalefact.Editable = 'off';
            app.AEP_scalefact.Value = 5.124;
            app.Image = uiimage(app.figure1);
            app.Image.ImageSource = 'image-removebg-preview-removebg-preview.png';
            app.Image_2 = uiimage(app.figure1);
            app.Image_2.ImageSource = '100313_web__1_-removebg-preview.png';
            app.AdaptiveParametersPanel = uipanel(app.figure1);
            app.AdaptiveParametersPanel.Title = 'Adaptive Parameters';
            app.FFTBlockSizeEditFieldLabel = uilabel(app.AdaptiveParametersPanel);
            app.FFTBlockSizeEditFieldLabel.Text = 'FFT Block Size';
            app.fft_block_size = uieditfield(app.AdaptiveParametersPanel, 'numeric');
            app.fft_block_size.Value = 20;
            app.SpectroBlockSizeLabel = uilabel(app.AdaptiveParametersPanel);
            app.SpectroBlockSizeLabel.Enable = 'off';
            app.SpectroBlockSizeLabel.Text = 'Spectro Block Size';
            app.spectro_block_size = uieditfield(app.AdaptiveParametersPanel, 'numeric');
            app.spectro_block_size.Enable = 'off';
            app.spectro_block_size.Value = 30;
            app.SpectroBlockSizeLabel_2 = uilabel(app.AdaptiveParametersPanel);
            app.SpectroBlockSizeLabel_2.Enable = 'off';
            app.SpectroBlockSizeLabel_2.Text = 'Can start spectro anal.';
            app.can_start_spectro = uieditfield(app.AdaptiveParametersPanel, 'numeric');
            app.can_start_spectro.Enable = 'off';
            app.can_start_spectro.Value = 30;
            app.incrementby = uilabel(app.AdaptiveParametersPanel);
            app.incrementby.Tag = 'txt_tone_intensity';
            app.incrementby.Text = 'Increment by';
            app.amplitude_step = uidropdown(app.AdaptiveParametersPanel);
            app.amplitude_step.Items = {'10 dB', '5 dB', '3 dB', '1 dB'};
            app.amplitude_step.Value = '5 dB';
            app.TotalLabel = uilabel(app.AdaptiveParametersPanel);
            app.TotalLabel.Text = 'Total α :';
            app.alpha_total = uieditfield(app.AdaptiveParametersPanel, 'numeric');
            app.alpha_total.Value = 0.05;
            app.FFTTTestParametersLabel = uilabel(app.AdaptiveParametersPanel);
            app.FFTTTestParametersLabel.Text = 'FFT T-Test Parameters';
            app.SpectrogramParametersLabel = uilabel(app.AdaptiveParametersPanel);
            app.SpectrogramParametersLabel.Text = 'Spectrogram Parameters';
            app.ofPermutationsLabel = uilabel(app.AdaptiveParametersPanel);
            app.ofPermutationsLabel.Enable = 'off';
            app.ofPermutationsLabel.Text = '# of Permutations';
            app.permutation_count = uieditfield(app.AdaptiveParametersPanel, 'numeric');
            app.permutation_count.Enable = 'off';
            app.permutation_count.Value = 1000;
            app.ofPermutationsLabel_2 = uilabel(app.AdaptiveParametersPanel);
            app.ofPermutationsLabel_2.Enable = 'off';
            app.ofPermutationsLabel_2.Text = 'Min # P Vals for Trend Anal.';
            app.permutation_N_pval_min = uieditfield(app.AdaptiveParametersPanel, 'numeric');
            app.permutation_N_pval_min.Enable = 'off';
            app.permutation_N_pval_min.Value = 5;
            app.MaxStimulusPresentationLabel = uilabel(app.AdaptiveParametersPanel);
            app.MaxStimulusPresentationLabel.Text = 'Max Stimulus Presentation';
            app.max_stim_presentation = uieditfield(app.AdaptiveParametersPanel, 'numeric');
            app.max_stim_presentation.Value = 500;
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Experiment Control';
            app.uipanel1.Tag = 'uipanel1';
            app.butt_go_calibrate = uibutton(app.uipanel1, 'push');
            app.butt_go_calibrate.ButtonPushedFcn = createCallbackFcn(app, @butt_go_calibrate_Callback, true);
            app.butt_go_calibrate.Tag = 'butt_go_calibrate';
            app.butt_go_calibrate.Text = 'C A L I B R A T E';
            app.butt_redo_calibration = uibutton(app.uipanel1, 'push');
            app.butt_redo_calibration.ButtonPushedFcn = createCallbackFcn(app, @butt_redo_calibration_Callback, true);
            app.butt_redo_calibration.Tag = 'butt_redo_calibration';
            app.butt_redo_calibration.Text = 'REDO CALIBRATION';
            app.butt_go = uibutton(app.uipanel1, 'push');
            app.butt_go.ButtonPushedFcn = createCallbackFcn(app, @butt_go_Callback, true);
            app.butt_go.Tag = 'butt_go';
            app.butt_go.Enable = 'off';
            app.butt_go.Text = 'START EXPERIMENT';
            app.butt_pause = uibutton(app.uipanel1, 'push');
            app.butt_pause.ButtonPushedFcn = createCallbackFcn(app, @butt_pause_Callback, true);
            app.butt_pause.Tag = 'butt_pause';
            app.butt_pause.Enable = 'off';
            app.butt_pause.Text = 'PAUSE';
            app.butt_stop = uibutton(app.uipanel1, 'push');
            app.butt_stop.Tag = 'butt_pause';
            app.butt_stop.Enable = 'off';
            app.butt_stop.Text = 'STOP';
            app.FrequenciesPanel = uipanel(app.figure1);
            app.FrequenciesPanel.Title = 'Frequencies';
            app.frequenciesList = uilabel(app.FrequenciesPanel);
            app.frequenciesList.Text = {'List of tested frequencies'; ''};
            app.CurrentstimulusPanel = uipanel(app.figure1);
            app.CurrentstimulusPanel.Title = 'Current stimulus';
            app.currentWaveformFig = uiaxes(app.CurrentstimulusPanel);
            app.labelforcurrentfreq = uilabel(app.CurrentstimulusPanel);
            app.labelforcurrentfreq.Text = 'Frequency: ';
            app.currentFreqLabel = uilabel(app.CurrentstimulusPanel);
            app.currentFreqLabel.Text = 'XXXX';
            app.HzLabel_2 = uilabel(app.CurrentstimulusPanel);
            app.HzLabel_2.Text = 'Hz';
            app.labelforcurrentfreq_2 = uilabel(app.CurrentstimulusPanel);
            app.labelforcurrentfreq_2.Text = 'Amplitude:';
            app.currentAmplitudeLabel = uilabel(app.CurrentstimulusPanel);
            app.currentAmplitudeLabel.Text = 'XXXX';
            app.dBLabel_2 = uilabel(app.CurrentstimulusPanel);
            app.dBLabel_2.Text = 'dB';
            app.LatencyStatusPanel = uipanel(app.figure1);
            app.LatencyStatusPanel.Title = 'Latency Status';
            app.LatencyThresholdLabel = uilabel(app.LatencyStatusPanel);
            app.LatencyThresholdLabel.Text = 'Latency Threshold:';
            app.LatencySamplesLabel = uilabel(app.LatencyStatusPanel);
            app.LatencySamplesLabel.Text = 'Latency Samples:';
            app.latencyThresholdLabel = uilabel(app.LatencyStatusPanel);
            app.latencyThresholdLabel.Text = 'XXXX';
            app.latencySampleLabel = uilabel(app.LatencyStatusPanel);
            app.latencySampleLabel.Text = 'XXXX';
            app.AmplitudeTrackingPanel = uipanel(app.figure1);
            app.AmplitudeTrackingPanel.Title = 'Amplitude Tracking';
            app.sixtyCycleTrackFig = uiaxes(app.AmplitudeTrackingPanel);
            title(app.sixtyCycleTrackFig, '60 Cycle')
            xlabel(app.sixtyCycleTrackFig, '# Trials In Average')
            ylabel(app.sixtyCycleTrackFig, 'Amplitude')
            app.stimFreqTrackFig = uiaxes(app.AmplitudeTrackingPanel);
            title(app.stimFreqTrackFig, 'Stimulus Freq.')
            xlabel(app.stimFreqTrackFig, '# Trials In Average')
            ylabel(app.stimFreqTrackFig, 'Amplitude')
            app.doubleFreqTrackFig = uiaxes(app.AmplitudeTrackingPanel);
            title(app.doubleFreqTrackFig, 'Double Freq.')
            xlabel(app.doubleFreqTrackFig, '# Trials In Average')
            ylabel(app.doubleFreqTrackFig, 'Amplitude')
            app.ExperimentStatusPanel = uipanel(app.figure1);
            app.ExperimentStatusPanel.Title = 'Experiment Status';
            app.ofblockscollectedLabel = uilabel(app.ExperimentStatusPanel);
            app.ofblockscollectedLabel.Text = '# of blocks collected:';
            app.fftCounter = uilabel(app.ExperimentStatusPanel);
            app.fftCounter.Text = '0';
            app.trialCounterCurrentStim = uilabel(app.ExperimentStatusPanel);
            app.trialCounterCurrentStim.Text = '0';
            app.TotaltrialsacrossallstimuliLabel = uilabel(app.ExperimentStatusPanel);
            app.TotaltrialsacrossallstimuliLabel.Text = 'Total trials across all stimuli';
            app.trialCounterALL = uilabel(app.ExperimentStatusPanel);
            app.trialCounterALL.Text = '0';
            app.TotaltrialsforcurrentstimulustypeLabel = uilabel(app.ExperimentStatusPanel);
            app.TotaltrialsforcurrentstimulustypeLabel.Text = 'Total trials for current stimulus type';
            app.AveragesPanel = uipanel(app.figure1);
            app.AveragesPanel.Title = 'Averages';
            app.wavAvgFig4 = uiaxes(app.AveragesPanel);
            title(app.wavAvgFig4, '4')
            app.wavAvgFig3 = uiaxes(app.AveragesPanel);
            title(app.wavAvgFig3, '3')
            app.wavAvgFig2 = uiaxes(app.AveragesPanel);
            title(app.wavAvgFig2, '2')
            app.wavAvgFig1 = uiaxes(app.AveragesPanel);
            title(app.wavAvgFig1, '1')
            app.fftAvgFig4 = uiaxes(app.AveragesPanel);
            title(app.fftAvgFig4, '4')
            app.fftAvgFig3 = uiaxes(app.AveragesPanel);
            title(app.fftAvgFig3, '3')
            app.fftAvgFig2 = uiaxes(app.AveragesPanel);
            title(app.fftAvgFig2, '2')
            app.fftAvgFig1 = uiaxes(app.AveragesPanel);
            title(app.fftAvgFig1, '1')
            app.pValTrackerFig4 = uiaxes(app.AveragesPanel);
            title(app.pValTrackerFig4, '4')
            app.pValTrackerFig3 = uiaxes(app.AveragesPanel);
            title(app.pValTrackerFig3, '3')
            app.pValTrackerFig2 = uiaxes(app.AveragesPanel);
            title(app.pValTrackerFig2, '2')
            app.pValTrackerFig1 = uiaxes(app.AveragesPanel);
            title(app.pValTrackerFig1, '1')
            app.maxFreq_field = uieditfield(app.figure1, 'text');
            app.maxFreq_field.Value = '700';
            app.minFreq_field = uieditfield(app.figure1, 'text');
            app.minFreq_field.Value = '70';
            app.text69_2 = uilabel(app.figure1);
            app.text69_2.Tag = 'text69';
            app.text69_2.Text = 'Max';
            app.text69_3 = uilabel(app.figure1);
            app.text69_3.Tag = 'text69';
            app.text69_3.Text = 'Min';
            app.HzLabel = uilabel(app.figure1);
            app.HzLabel.Text = 'Hz';
            app.HardwareFunctionCheckPanel = uipanel(app.figure1);
            app.HardwareFunctionCheckPanel.Title = 'Hardware Function Check';
            app.bioamp_checkbox = uicheckbox(app.HardwareFunctionCheckPanel);
            app.bioamp_checkbox.Text = 'Hydrophone Amp';
            app.bioamp_checkbox_2 = uicheckbox(app.HardwareFunctionCheckPanel);
            app.bioamp_checkbox_2.Text = 'Fireface';
            app.bioamp_checkbox_3 = uicheckbox(app.HardwareFunctionCheckPanel);
            app.bioamp_checkbox_3.Text = 'Bioamps';
            app.bioamp_checkbox_4 = uicheckbox(app.HardwareFunctionCheckPanel);
            app.bioamp_checkbox_4.Text = 'Speaker Amp';
            app.bioamp_checkbox_5 = uicheckbox(app.HardwareFunctionCheckPanel);
            app.bioamp_checkbox_5.Text = 'Oscilloscope';
            app.bioamp_checkbox_6 = uicheckbox(app.HardwareFunctionCheckPanel);
            app.bioamp_checkbox_6.Text = 'Pump system';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = go_Adaptive_AEP(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)go_Fish_AEP_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end