classdef adapt_aep < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        DropDown_test_tag              matlab.ui.control.DropDown
        Label_5                        matlab.ui.control.Label
        EditField_timer                matlab.ui.control.NumericEditField
        TimerdurationLabel             matlab.ui.control.Label
        DropDown_test_mode             matlab.ui.control.DropDown
        Label_signal_snr               matlab.ui.control.Label
        SignalSNRLabel                 matlab.ui.control.Label
        dBSPLLabel_2                   matlab.ui.control.Label
        dBSPLLabel                     matlab.ui.control.Label
        Label_tank_noise_floor         matlab.ui.control.Label
        TanknoisefloorLabel            matlab.ui.control.Label
        TempButton                     matlab.ui.control.Button
        HealthButton                   matlab.ui.control.Button
        DropDown_sex                   matlab.ui.control.DropDown
        PauseButton                    matlab.ui.control.Button
        Label_time_elapsed             matlab.ui.control.Label
        TotaltimeelapsedLabel          matlab.ui.control.Label
        peramplitudeLabel              matlab.ui.control.Label
        Label_grand_total              matlab.ui.control.Label
        GrandNtrialsLabel              matlab.ui.control.Label
        Label_number_trials_presented  matlab.ui.control.Label
        Label_current_amp              matlab.ui.control.Label
        Label_6                        matlab.ui.control.Label
        ValidTotalTrialsLabel          matlab.ui.control.Label
        CurrentamplitudeLabel          matlab.ui.control.Label
        DropDown_tone_burst            matlab.ui.control.DropDown
        StatusLabel                    matlab.ui.control.Label
        InitializeButton               matlab.ui.control.Button
        AnalysisLabel                  matlab.ui.control.Label
        MonitorLabel                   matlab.ui.control.Label
        DropDown_response_feature      matlab.ui.control.DropDown
        StartButton                    matlab.ui.control.Button
        CalibrateButton                matlab.ui.control.Button
        logo                           matlab.ui.control.Image
        EditField_max_trials           matlab.ui.control.NumericEditField
        EditField_frequency            matlab.ui.control.NumericEditField
        EditField_ID                   matlab.ui.control.NumericEditField
        ExperimentLabel                matlab.ui.control.Label
        MaximumtrialsLabel_2           matlab.ui.control.Label
        HzLabel                        matlab.ui.control.Label
        StimulusLabel                  matlab.ui.control.Label
        SexLabel                       matlab.ui.control.Label
        IDLabel                        matlab.ui.control.Label
        SubjectLabel                   matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        minutesLabel                   matlab.ui.control.Label
        SelectTestModetopopulateLabel  matlab.ui.control.Label
        Label_rejection_rate           matlab.ui.control.Label
        UIAxes_funfetti                matlab.ui.control.UIAxes
        UIAxes_live_fft                matlab.ui.control.UIAxes
        UIAxes_model_3                 matlab.ui.control.UIAxes
        UIAxes_gate                    matlab.ui.control.UIAxes
        UIAxes_tank_noise_floor        matlab.ui.control.UIAxes
        UIAxes_signal_SNR              matlab.ui.control.UIAxes
        UIAxes_slope_est               matlab.ui.control.UIAxes
        UIAxes_thresh_est              matlab.ui.control.UIAxes
        UIAxes_diff_fft                matlab.ui.control.UIAxes
        UIAxes_health                  matlab.ui.control.UIAxes
        UIAxes_model                   matlab.ui.control.UIAxes
        UIAxes_boot                    matlab.ui.control.UIAxes
        UIAxes_ch4                     matlab.ui.control.UIAxes
        UIAxes_ch3                     matlab.ui.control.UIAxes
        UIAxes_ch2                     matlab.ui.control.UIAxes
        UIAxes_ch1                     matlab.ui.control.UIAxes
        UIAxes_hydrophone              matlab.ui.control.UIAxes
    end


    properties (Access = public)
        ex % Experiment structure
        PauseFlag logical = false
    end

    methods (Access = private)
        function clearAllAxes(app)
            cla(app.UIAxes_hydrophone);
            title(app.UIAxes_hydrophone,'Hydrophone')
            cla(app.UIAxes_ch1);
            cla(app.UIAxes_ch2);
            cla(app.UIAxes_ch3);
            cla(app.UIAxes_ch4);
            cla(app.UIAxes_boot);
            cla(app.UIAxes_model);
            title(app.UIAxes_model,'Model')
            cla(app.UIAxes_model_3);
            title(app.UIAxes_model_3,'Model')
            cla(app.UIAxes_diff_fft);
            cla(app.UIAxes_thresh_est);
            cla(app.UIAxes_slope_est);
            cla(app.UIAxes_gate);
            cla(app.UIAxes_tank_noise_floor);
            cla(app.UIAxes_signal_SNR);

            % --- funfetti: also undo the Mixed-mode heatmap (imagesc) state ---
            cla(app.UIAxes_funfetti);
            delete(allchild(app.UIAxes_funfetti));
            delete(app.UIAxes_funfetti.Legend);              % errorbar legend from Timed/Static
            app.UIAxes_funfetti.YDir           = 'normal';   % imagesc set this to 'reverse'
            app.UIAxes_funfetti.XLimMode       = 'auto';
            app.UIAxes_funfetti.YLimMode       = 'auto';
            app.UIAxes_funfetti.CLimMode       = 'auto';
            app.UIAxes_funfetti.XTickMode      = 'auto';
            app.UIAxes_funfetti.YTickMode      = 'auto';
            app.UIAxes_funfetti.XTickLabelMode = 'auto';
            app.UIAxes_funfetti.YTickLabelMode = 'auto';
            colormap(app.UIAxes_funfetti, 'parula');         % drop the custom blue heatmap colormap
            title(app.UIAxes_funfetti, '2f Response Tracking')
            xlabel(app.UIAxes_funfetti, 'Iteration')
            ylabel(app.UIAxes_funfetti, '2f Magnitude (\muV)')
            app.UIAxes_funfetti.Box = 'on';
            app.UIAxes_funfetti.Visible = 'off';

            cla(app.UIAxes_live_fft);
            delete(allchild(app.UIAxes_live_fft));
            delete(app.UIAxes_live_fft.Legend);
            title(app.UIAxes_live_fft, 'Live FFT Monitor')
            xlabel(app.UIAxes_live_fft, 'Frequency (Hz)')
            ylabel(app.UIAxes_live_fft, 'Magnitude (\muV)')
            app.UIAxes_live_fft.Box = 'on';
            app.UIAxes_live_fft.Visible = 'off';
        end

        function setAxesVisibility(app)
            mode = app.DropDown_test_mode.Value;
            on   = ismember(mode, {'Timed','Static trial count','Mixed stimuli'});
            adp  = strcmp(mode, 'Adaptive');
            app.UIAxes_funfetti.Visible   = on;
            app.UIAxes_live_fft.Visible   = on;
            app.UIAxes_model_3.Visible    = adp;
            app.UIAxes_gate.Visible       = adp;
            app.UIAxes_slope_est.Visible  = adp;
            app.UIAxes_thresh_est.Visible = adp;
            app.UIAxes_diff_fft.Visible   = adp;
            app.UIAxes_model.Visible      = adp;
            app.UIAxes_boot.Visible       = adp;
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath('C:\Users\AEP\Desktop\adapt_aep\src\matlab'))
            [y, Fs] = audioread('open_app.mp3');
            sound(y, Fs)
        end

        % Button pushed function: StartButton
        function start_exp(app, event)
             fprintf('\nStarting experiment...\n')
            [y, Fs] = audioread('button_press.mp3');
            sound(y, Fs)

            % Clear GUI
            clearAllAxes(app)
            setAxesVisibility(app)
            app.Label_current_amp.Text = 'X';
            app.Label_number_trials_presented.Text = 'X';
            app.Label_rejection_rate.Text = 'X';
            app.Label_grand_total.Text = 'X';
            app.Label_time_elapsed.Text = 'X';
            app.Label_tank_noise_floor.Text = 'X';
            app.Label_signal_snr.Text = 'X';

            app.CalibrateButton.Enable = 'off';
            app.InitializeButton.Enable = 'off';
            app.TempButton.Enable = 'off';
            app.HealthButton.Enable = 'off';
            app.PauseButton.Enable = 'off';

            % Rip it
            if strcmp(app.DropDown_test_mode.Value,'Adaptive') || strcmp(app.DropDown_test_mode.Value,'Timed') || ...
                    strcmp(app.DropDown_test_mode.Value,'Static trial count')
                app.ex = run_single(app);
                fprintf('\nFinished testing at current frequency.')
            elseif strcmp(app.DropDown_test_mode.Value,'Mixed stimuli')
                app.ex = run_mixed(app);
                fprintf('\nFinished testing stimulus schedule.')
            end
        
            % Reset all controls to initial state
            app.EditField_ID.Enable = 'on';
            app.EditField_frequency.Enable = 'on';
            app.EditField_frequency.Value = [];
            app.EditField_max_trials.Enable = 'on';
            app.DropDown_response_feature.Enable = 'on';
            app.DropDown_response_feature.Value = 'Double frequency';
            app.DropDown_test_mode.Enable = 'on';
            app.DropDown_test_mode.Value = 'Select test mode';
            app.DropDown_test_tag.Enable = 'on';
            app.DropDown_test_tag.Value = 'Select test tag';
            app.DropDown_sex.Enable = 'on';
            app.DropDown_tone_burst.Enable = 'on';
            app.DropDown_tone_burst.Value = 'Tone burst';

            app.InitializeButton.Enable = 'on';
            app.CalibrateButton.Enable = 'off';
            app.HealthButton.Enable = 'off';
            app.TempButton.Enable = 'off';
            app.StartButton.Enable = 'off';
            app.PauseButton.Enable = 'off';

            app.Label_current_amp.Text = 'X';
            app.Label_number_trials_presented.Text = 'X';
            app.Label_rejection_rate.Text = 'X';
            app.Label_grand_total.Text = 'X';
            app.Label_time_elapsed.Text = 'X';
            app.Label_tank_noise_floor.Text = 'X';
            app.Label_signal_snr.Text = 'X';
            app.EditField_max_trials.Visible = "off";

            app.EditField_max_trials.Visible   = 'off';
            app.EditField_timer.Visible        = 'off';
            app.TimerdurationLabel.Visible     = 'off';
            app.minutesLabel.Visible           = 'off';
            app.MaximumtrialsLabel_2.Visible   = 'off';
            app.peramplitudeLabel.Visible      = 'off';

            app.SelectTestModetopopulateLabel.Visible = "on";

            app.ex = [];
            app.PauseFlag = false;
            clearAllAxes(app);
            setAxesVisibility(app)

            [y, Fs] = audioread('finished_exp.mp3');
            sound(y, Fs)
        end

        % Button pushed function: CalibrateButton
        function start_calibration(app, event)
            fprintf('\nStarting calibration...\n')
            [y, Fs] = audioread('button_press.mp3');
            sound(y, Fs)

            % Check if ex has been initialized
            if isempty(app.ex) || ~isfield(app.ex, 'info')
                uialert(app.UIFigure, 'Please initialize experiment first.', 'Not Initialized');
                return;
            end

            % Create the calibration app
            calibration_app = calibrate_stimulus();

            % Initialize it with ex
            calibration_app.initializeWithEx(app.ex);

            % Automatic button push
            t = timer('StartDelay', 0.1, 'TimerFcn', @(varargin) calibration_app.runCalibrate());
            start(t);
            uiwait(calibration_app.UIFigure);  % now listening before button fires
            delete(t);

            % Get the updated ex back
            app.ex = calibration_app.ex;

            % Clean up
            delete(calibration_app)

            app.CalibrateButton.Enable = 'on';
            app.HealthButton.Enable = 'on';
            app.StartButton.Enable = "on";

            [y, Fs] = audioread('completed.mp3');
            sound(y, Fs)

            fprintf('\nFinished calibration\n')
        end

        % Button pushed function: InitializeButton
        function start_init(app, event)
            fprintf('\nStarting initalization\n')

            % Initialize ex structure
            app.ex = setup_ex(app);
            [y, Fs] = audioread('button_press.mp3');
            sound(y, Fs)

            % Check if all required fields are filled
            if isempty(app.EditField_ID.Value) || strcmp(app.DropDown_sex.Value,'X') || ...
                    isnan(app.EditField_frequency.Value) || strcmp(app.DropDown_response_feature.Value, 'Select response feature') || isnan(app.EditField_max_trials.Value) || ...
                    strcmp(app.DropDown_test_mode.Value,'Select test mode') || strcmp(app.DropDown_test_tag.Value, 'Select test tag')

                uialert(app.UIFigure, 'Make sure all fields are filled out prior to continuing.', 'Missing Fields');
                return;
            end

            % Setup info
            app.ex = setup_info(app.ex,app); % User input of experiment parameters
            
            % Extract GUI Values
            app.ex.info.animal.subject_ID = app.EditField_ID.Value;
            app.ex.info.animal.sex = app.DropDown_sex.Value;
            app.ex.info.stimulus.frequency_hz = app.EditField_frequency.Value;
            app.ex.info.stimulus.type = app.DropDown_tone_burst.Value;
            app.ex.info.experiment.response_feature = app.DropDown_response_feature.Value;
            app.ex.info.trials.max_trials = app.EditField_max_trials.Value;
            app.ex.info.experiment.timer_dur_min = app.EditField_timer.Value;
            app.ex.info.experiment.exp_type = app.DropDown_test_mode.Value;
            app.ex.info.experiment.test_tag = app.DropDown_test_tag.Value;
            app.ex.info.animal.filename_root = sprintf('%s_%d_%gHz', ...
                app.ex.info.animal.species_name, ...
                app.ex.info.animal.subject_ID, ...
                app.ex.info.stimulus.frequency_hz);

            % Set up sets of ex
            app.ex = setup_health(app.ex);
            app.ex = setup_block(app.ex); % Per amplitude meta/data
            app.ex = setup_hardware(app.ex);
            if strcmp(app.DropDown_test_mode.Value,'Adaptive') || strcmp(app.DropDown_test_mode.Value,'Mixed stimuli')
                app.ex = setup_analysis(app.ex); % Analysis deta/data
            end
            if strcmp(app.DropDown_test_mode.Value,'Adaptive')
                app.ex = setup_model(app.ex);
            end

            %% Generate Stimulus Template
            app.ex = select_experiment_stim_params(app.ex);
            app.ex = make_stimulus_template(app.ex);

            % GUI Buttons
            app.InitializeButton.Enable = 'off';
            app.HealthButton.Enable = 'on';
            app.EditField_ID.Enable = 'off';
            app.EditField_frequency.Enable = 'off';
            app.EditField_max_trials.Enable = 'off';
            app.DropDown_response_feature.Enable = 'off';
            app.DropDown_test_mode.Enable = 'off';
            app.DropDown_test_tag.Enable = 'off';
            app.DropDown_sex.Enable = 'on';
            app.DropDown_sex.Enable = "off";
            app.DropDown_tone_burst.Enable = "off";
            app.DropDown_test_mode.Enable = "off";

            app.EditField_max_trials.Enable   = 'off';
            app.EditField_max_trials.Editable = 'off';

            app.EditField_timer.Editable = 'off';
            app.EditField_timer.Enable   = 'off';

            [y, Fs] = audioread('completed.mp3');
            sound(y, Fs)

            fprintf('\nInitialization complete\n')

        end

        % Button pushed function: HealthButton
        function HealthButtonPushed(app, event)
             fprintf('\nStarting health check...\n')
             [y, Fs] = audioread('button_press.mp3');
            sound(y, Fs)
            % app.HealthButton.Enable = 'off';
            app.ex = check_health(app.ex,app, 1);

            app.HealthButton.Enable = 'on';
            app.TempButton.Enable = 'off';
            app.CalibrateButton.Enable = 'on';


            [y, Fs] = audioread('completed.mp3');
            sound(y, Fs)
            fprintf('\nFinished health check\n')
        end

        % Button pushed function: TempButton
        function TempButtonPushed(app, event)
            fprintf('\nStarting temperature check...\n')
            [y, Fs] = audioread('button_press.mp3');
            sound(y, Fs)
            app.TempButton.Enable = 'off';
            app.StartButton.Enable = 'on';
            [y, Fs] = audioread('completed.mp3');
            sound(y, Fs)
            fprintf('\nFinished temperature check\n')
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            [y, Fs] = audioread('button_press.mp3');
            sound(y, Fs)
            app.PauseFlag = true;
            [y, Fs] = audioread('completed.mp3');
            sound(y, Fs)
        end

        % Value changed function: DropDown_test_mode
        function DropDown_test_modeValueChanged(app, event)
            value = app.DropDown_test_mode.Value;
            app.SelectTestModetopopulateLabel.Visible = "off";

            isTimed    = strcmp(value, 'Timed');
            isStatic   = strcmp(value, 'Static trial count');
            isAdaptive = strcmp(value, 'Adaptive');
            isMixed    = strcmp(value, 'Mixed stimuli');
            showTrials = isStatic || isAdaptive;

            % Timer controls
            app.EditField_timer.Visible    = isTimed;
            app.TimerdurationLabel.Visible = isTimed;
            app.minutesLabel.Visible       = isTimed;

            % Max trials controls
            app.EditField_max_trials.Visible  = showTrials;
            app.MaximumtrialsLabel_2.Visible  = showTrials;
            app.peramplitudeLabel.Visible     = showTrials;

            % Defaults per mode
            if isTimed
                app.EditField_timer.Editable   = 'on';
                app.EditField_timer.Enable     = 'on';
                app.EditField_timer.Value      = 30;
                app.EditField_max_trials.Value = 200;
            elseif isStatic
                app.EditField_max_trials.Enable   = 'on';
                app.EditField_max_trials.Editable = 'on';
                app.EditField_max_trials.Value    = 1024;
                app.EditField_timer.Value         = 30;
            elseif isAdaptive
                app.EditField_max_trials.Enable   = 'on';
                app.EditField_max_trials.Editable = 'on';
                app.EditField_max_trials.Value    = 256;
                app.EditField_timer.Value         = 30;
            end

            % Axes visibility
            showLivePlots = isTimed || isStatic || isMixed;
            app.UIAxes_funfetti.Visible   = showLivePlots;
            app.UIAxes_live_fft.Visible   = showLivePlots;
            app.UIAxes_model_3.Visible    = isAdaptive;
            app.UIAxes_gate.Visible       = isAdaptive;
            app.UIAxes_slope_est.Visible  = isAdaptive;
            app.UIAxes_thresh_est.Visible = isAdaptive;
            app.UIAxes_diff_fft.Visible   = isAdaptive;
            app.UIAxes_model.Visible      = isAdaptive;
            app.UIAxes_boot.Visible       = isAdaptive;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_hydrophone = uiaxes(app.UIFigure);
            title(app.UIAxes_hydrophone, 'Hydrophone')
            ylabel(app.UIAxes_hydrophone, 'Amplitude (mV)')
            zlabel(app.UIAxes_hydrophone, 'Z')
            app.UIAxes_ch1 = uiaxes(app.UIFigure);
            title(app.UIAxes_ch1, 'Channel 1')
            zlabel(app.UIAxes_ch1, 'Z')
            app.UIAxes_ch2 = uiaxes(app.UIFigure);
            title(app.UIAxes_ch2, 'Channel 2')
            ylabel(app.UIAxes_ch2, 'Amplitude (\muV)')
            zlabel(app.UIAxes_ch2, 'Z')
            app.UIAxes_ch3 = uiaxes(app.UIFigure);
            title(app.UIAxes_ch3, 'Channel 3')
            zlabel(app.UIAxes_ch3, 'Z')
            app.UIAxes_ch4 = uiaxes(app.UIFigure);
            title(app.UIAxes_ch4, 'Channel 4')
            xlabel(app.UIAxes_ch4, 'Time (s)')
            zlabel(app.UIAxes_ch4, 'Z')
            app.UIAxes_boot = uiaxes(app.UIFigure);
            title(app.UIAxes_boot, 'Bootstrap Results')
            ylabel(app.UIAxes_boot, 'Lower CI Value')
            zlabel(app.UIAxes_boot, 'Z')
            app.UIAxes_boot.Visible = 'off';
            app.UIAxes_model = uiaxes(app.UIFigure);
            title(app.UIAxes_model, 'Model')
            xlabel(app.UIAxes_model, 'Stimulus Amplitude (dB SPL)')
            ylabel(app.UIAxes_model, '2f Amplitude (\muV)')
            zlabel(app.UIAxes_model, 'Z')
            app.UIAxes_model.Visible = 'off';
            app.UIAxes_health = uiaxes(app.UIFigure);
            title(app.UIAxes_health, 'EKG')
            xlabel(app.UIAxes_health, 'Time (s)')
            ylabel(app.UIAxes_health, 'Amplitude (\muV)')
            app.UIAxes_diff_fft = uiaxes(app.UIFigure);
            title(app.UIAxes_diff_fft, 'Difference FFT')
            xlabel(app.UIAxes_diff_fft, 'Frequency (Hz)')
            ylabel(app.UIAxes_diff_fft, 'Amplitude (\muV)')
            zlabel(app.UIAxes_diff_fft, 'Z')
            app.UIAxes_diff_fft.Visible = 'off';
            app.UIAxes_thresh_est = uiaxes(app.UIFigure);
            title(app.UIAxes_thresh_est, 'Threshold Estimate')
            xlabel(app.UIAxes_thresh_est, 'Iteration')
            ylabel(app.UIAxes_thresh_est, 'x0')
            zlabel(app.UIAxes_thresh_est, 'Z')
            app.UIAxes_thresh_est.Visible = 'off';
            app.UIAxes_slope_est = uiaxes(app.UIFigure);
            title(app.UIAxes_slope_est, 'Slope Estimate')
            xlabel(app.UIAxes_slope_est, 'Iteration')
            ylabel(app.UIAxes_slope_est, 'm')
            zlabel(app.UIAxes_slope_est, 'Z')
            app.UIAxes_slope_est.Visible = 'off';
            app.UIAxes_signal_SNR = uiaxes(app.UIFigure);
            title(app.UIAxes_signal_SNR, 'Signal SNR')
            ylabel(app.UIAxes_signal_SNR, 'SNR (dB)')
            zlabel(app.UIAxes_signal_SNR, 'Z')
            app.UIAxes_tank_noise_floor = uiaxes(app.UIFigure);
            title(app.UIAxes_tank_noise_floor, 'Tank Noise Floor ')
            ylabel(app.UIAxes_tank_noise_floor, 'dB SPL')
            zlabel(app.UIAxes_tank_noise_floor, 'Z')
            app.UIAxes_gate = uiaxes(app.UIFigure);
            title(app.UIAxes_gate, 'Detection Gate Type ')
            xlabel(app.UIAxes_gate, 'Gate type')
            ylabel(app.UIAxes_gate, 'Frequency')
            zlabel(app.UIAxes_gate, 'Z')
            app.UIAxes_gate.Visible = 'off';
            app.UIAxes_model_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_model_3, 'Model')
            xlabel(app.UIAxes_model_3, 'Stimulus Amplitude (dB SPL)')
            ylabel(app.UIAxes_model_3, '2f Amplitude (\muV)')
            zlabel(app.UIAxes_model_3, 'Z')
            app.UIAxes_model_3.Visible = 'off';
            app.UIAxes_live_fft = uiaxes(app.UIFigure);
            title(app.UIAxes_live_fft, 'Live FFT Monitor')
            xlabel(app.UIAxes_live_fft, 'Frequency (Hz)')
            ylabel(app.UIAxes_live_fft, 'Magnitude (\muV)')
            zlabel(app.UIAxes_live_fft, 'Z')
            app.UIAxes_live_fft.Visible = 'off';
            app.UIAxes_funfetti = uiaxes(app.UIFigure);
            title(app.UIAxes_funfetti, '2f Response Tracking')
            xlabel(app.UIAxes_funfetti, 'Iteration')
            ylabel(app.UIAxes_funfetti, '2f Magnitude (\muV)')
            zlabel(app.UIAxes_funfetti, 'Z')
            app.UIAxes_funfetti.Visible = 'off';
            app.Label_rejection_rate = uilabel(app.UIFigure);
            app.Label_rejection_rate.Text = 'X';
            app.SelectTestModetopopulateLabel = uilabel(app.UIFigure);
            app.SelectTestModetopopulateLabel.Text = 'Select ''Test Mode'' to populate';
            app.minutesLabel = uilabel(app.UIFigure);
            app.minutesLabel.Visible = 'off';
            app.minutesLabel.Text = 'minute(s)';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '__________________________';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '__________________________';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '__________________________';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '__________________________';
            app.SubjectLabel = uilabel(app.UIFigure);
            app.SubjectLabel.Text = 'Subject';
            app.IDLabel = uilabel(app.UIFigure);
            app.IDLabel.Text = 'ID #:';
            app.SexLabel = uilabel(app.UIFigure);
            app.SexLabel.Text = 'Sex:';
            app.StimulusLabel = uilabel(app.UIFigure);
            app.StimulusLabel.Text = 'Stimulus';
            app.HzLabel = uilabel(app.UIFigure);
            app.HzLabel.Text = 'Hz';
            app.MaximumtrialsLabel_2 = uilabel(app.UIFigure);
            app.MaximumtrialsLabel_2.Visible = 'off';
            app.MaximumtrialsLabel_2.Text = 'Maximum trials:';
            app.ExperimentLabel = uilabel(app.UIFigure);
            app.ExperimentLabel.Text = 'Experiment';
            app.EditField_ID = uieditfield(app.UIFigure, 'numeric');
            app.EditField_ID.AllowEmpty = 'on';
            app.EditField_ID.Value = [];
            app.EditField_frequency = uieditfield(app.UIFigure, 'numeric');
            app.EditField_frequency.AllowEmpty = 'on';
            app.EditField_frequency.Value = [];
            app.EditField_max_trials = uieditfield(app.UIFigure, 'numeric');
            app.EditField_max_trials.Editable = 'off';
            app.EditField_max_trials.Enable = 'off';
            app.EditField_max_trials.Visible = 'off';
            app.logo = uiimage(app.UIFigure);
            app.logo.ImageSource = fullfile(pathToMLAPP, 'adapt_aep_logo.png');
            app.CalibrateButton = uibutton(app.UIFigure, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @start_calibration, true);
            app.CalibrateButton.Enable = 'off';
            app.CalibrateButton.Text = 'Calibrate';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @start_exp, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.DropDown_response_feature = uidropdown(app.UIFigure);
            app.DropDown_response_feature.Items = {'Double frequency', 'Select response feature'};
            app.DropDown_response_feature.Value = 'Double frequency';
            app.MonitorLabel = uilabel(app.UIFigure);
            app.MonitorLabel.Text = 'Monitor';
            app.AnalysisLabel = uilabel(app.UIFigure);
            app.AnalysisLabel.Text = 'Analysis';
            app.InitializeButton = uibutton(app.UIFigure, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @start_init, true);
            app.InitializeButton.Text = 'Initialize';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Status';
            app.DropDown_tone_burst = uidropdown(app.UIFigure);
            app.DropDown_tone_burst.Items = {'Tone burst'};
            app.DropDown_tone_burst.Value = 'Tone burst';
            app.CurrentamplitudeLabel = uilabel(app.UIFigure);
            app.CurrentamplitudeLabel.Text = 'Current amplitude: ';
            app.ValidTotalTrialsLabel = uilabel(app.UIFigure);
            app.ValidTotalTrialsLabel.Text = 'Valid/Total Trials:';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '/';
            app.Label_current_amp = uilabel(app.UIFigure);
            app.Label_current_amp.Text = 'X';
            app.Label_number_trials_presented = uilabel(app.UIFigure);
            app.Label_number_trials_presented.Text = 'X';
            app.GrandNtrialsLabel = uilabel(app.UIFigure);
            app.GrandNtrialsLabel.Text = 'Grand N trials: ';
            app.Label_grand_total = uilabel(app.UIFigure);
            app.Label_grand_total.Text = 'X';
            app.peramplitudeLabel = uilabel(app.UIFigure);
            app.peramplitudeLabel.Visible = 'off';
            app.peramplitudeLabel.Text = 'per amplitude';
            app.TotaltimeelapsedLabel = uilabel(app.UIFigure);
            app.TotaltimeelapsedLabel.Text = 'Total time elapsed:';
            app.Label_time_elapsed = uilabel(app.UIFigure);
            app.Label_time_elapsed.Text = 'X';
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Enable = 'off';
            app.PauseButton.Text = 'Pause';
            app.DropDown_sex = uidropdown(app.UIFigure);
            app.DropDown_sex.Items = {'X', 'F', 'M'};
            app.DropDown_sex.Value = 'X';
            app.HealthButton = uibutton(app.UIFigure, 'push');
            app.HealthButton.ButtonPushedFcn = createCallbackFcn(app, @HealthButtonPushed, true);
            app.HealthButton.Enable = 'off';
            app.HealthButton.Text = 'Health';
            app.TempButton = uibutton(app.UIFigure, 'push');
            app.TempButton.ButtonPushedFcn = createCallbackFcn(app, @TempButtonPushed, true);
            app.TempButton.Enable = 'off';
            app.TempButton.Text = 'Temp';
            app.TanknoisefloorLabel = uilabel(app.UIFigure);
            app.TanknoisefloorLabel.Text = 'Tank noise floor:';
            app.Label_tank_noise_floor = uilabel(app.UIFigure);
            app.Label_tank_noise_floor.Text = 'X';
            app.dBSPLLabel = uilabel(app.UIFigure);
            app.dBSPLLabel.Text = 'dB SPL';
            app.dBSPLLabel_2 = uilabel(app.UIFigure);
            app.dBSPLLabel_2.Text = 'dB SPL';
            app.SignalSNRLabel = uilabel(app.UIFigure);
            app.SignalSNRLabel.Text = 'Signal SNR:';
            app.Label_signal_snr = uilabel(app.UIFigure);
            app.Label_signal_snr.Text = 'X';
            app.DropDown_test_mode = uidropdown(app.UIFigure);
            app.DropDown_test_mode.Items = {'Select test mode', 'Timed', 'Static trial count', 'Adaptive', 'Mixed stimuli'};
            app.DropDown_test_mode.ValueChangedFcn = createCallbackFcn(app, @DropDown_test_modeValueChanged, true);
            app.DropDown_test_mode.Value = 'Select test mode';
            app.TimerdurationLabel = uilabel(app.UIFigure);
            app.TimerdurationLabel.Visible = 'off';
            app.TimerdurationLabel.Text = 'Timer duration:';
            app.EditField_timer = uieditfield(app.UIFigure, 'numeric');
            app.EditField_timer.Editable = 'off';
            app.EditField_timer.Enable = 'off';
            app.EditField_timer.Visible = 'off';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '__________________________';
            app.DropDown_test_tag = uidropdown(app.UIFigure);
            app.DropDown_test_tag.Items = {'Select test tag', 'benzo', '1024_trial', 'adaptive', 'mixed_stimuli'};
            app.DropDown_test_tag.Value = 'Select test tag';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = adapt_aep

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end