classdef adapt_aep_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        InitializeButton              matlab.ui.control.Button
        Hydrophoneamplifiersetto100mVPaLabel  matlab.ui.control.Label
        CheckBox_hydrophone_amp       matlab.ui.control.CheckBox
        DropDown_health               matlab.ui.control.DropDown
        AnalysisLabel                 matlab.ui.control.Label
        MonitorLabel                  matlab.ui.control.Label
        DropDown_response_feature     matlab.ui.control.DropDown
        StartexperimentButton         matlab.ui.control.Button
        CalibratestimulusButton       matlab.ui.control.Button
        EditField_sex                 matlab.ui.control.EditField
        logo                          matlab.ui.control.Image
        EditField_max_trials          matlab.ui.control.NumericEditField
        EditField_N_trials_per_block  matlab.ui.control.NumericEditField
        EditField_amplitude           matlab.ui.control.NumericEditField
        EditField_frequency           matlab.ui.control.NumericEditField
        EditField_ID                  matlab.ui.control.NumericEditField
        ExperimentLabel               matlab.ui.control.Label
        MaximumtrialsLabel_2          matlab.ui.control.Label
        NtrialsblockLabel_2           matlab.ui.control.Label
        ResponsefeatureLabel          matlab.ui.control.Label
        StartingamplitudedBSPLLabel   matlab.ui.control.Label
        FrequencyHzLabel              matlab.ui.control.Label
        StimulusLabel                 matlab.ui.control.Label
        HealthLabel                   matlab.ui.control.Label
        SexLabel                      matlab.ui.control.Label
        IDLabel                       matlab.ui.control.Label
        SubjectLabel                  matlab.ui.control.Label
        UIAxes_model                  matlab.ui.control.UIAxes
        UIAxes_boot                   matlab.ui.control.UIAxes
        UIAxes_ch4                    matlab.ui.control.UIAxes
        UIAxes_ch3                    matlab.ui.control.UIAxes
        UIAxes_ch2                    matlab.ui.control.UIAxes
        UIAxes_ch1                    matlab.ui.control.UIAxes
        UIAxes_hydrophone             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ex % experiment structure
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartexperimentButton
        function start_exp(app, event)
        addpath(genpath("\\wsl$\ubuntu\home\aoih\adapt_aep\src\matlab"))

        app.CalibratestimulusButton.Enable = 'off';
        app.InitializeButton.Enable = 'off';

        % Rip it
        main_loop(app);
        end

        % Button pushed function: CalibratestimulusButton
        function start_calibration(app, event)
        addpath(genpath("\\wsl$\ubuntu\home\aoih\adapt_aep\src\matlab"))
        
        % Check if ex has been initialized
        if isempty(app.ex) || ~isfield(app.ex, 'info')
            uialert(app.UIFigure, 'Please initialize experiment first.', 'Not Initialized');
            return;
        end

        % Create dialog asking to load existing or create new calibration
        choice = uiconfirm(app.UIFigure, ...
            'Do you want to load an existing calibration file or create a new one?', ...
            'Calibration Options', ...
            'Options', {'Load Existing', 'Create New'}, ...
            'DefaultOption', 1);
        
        if strcmp(choice, 'Load Existing')
            % Allow user to select calibration file
            [file, path] = uigetfile('*.mat', 'Select Calibration File');
            
            if file == 0
                % User cancelled
                return;
            end
            
            try
                % Load calibration data
                loaded = load(fullfile(path, file), 'calibration_data');
                fprintf('Loaded calibration data from: %s\n', file);
                app.ex.info.stimulus.calibration = loaded.calibration_data;
            catch ME
                uialert(app.UIFigure, sprintf('Error loading calibration: %s', ME.message), 'Load Error');
            end
            
        else
            try
            % Create new calibration
            calibrate_stimulus(app.ex);
            catch ME
                uialert(app.UIFigure, sprintf('Error with calibration software: %s', ME.message), 'Calibration Error');
            end
        end
        
        app.CalibratestimulusButton.Text = 'Redo calibration';
        app.StartexperimentButton.Enable = 'on';
        end

        % Button pushed function: InitializeButton
        function start_init(app, event)
        addpath(genpath("\\wsl$\ubuntu\home\aoih\adapt_aep\src\matlab"))

        % Check if all required fields are filled
        if isempty(app.EditField_ID.Value) || isempty(app.EditField_sex.Value) || ...
           isempty(app.DropDown_health.Value) || isnan(app.EditField_frequency.Value) || ...
           isnan(app.EditField_amplitude.Value) || isempty(app.DropDown_response_feature.Value) || ...
           isnan(app.EditField_N_trials_per_block.Value) || isnan(app.EditField_max_trials.Value)
            
            uialert(app.UIFigure, 'Make sure all fields are filled out prior to continuing.', 'Missing Fields');
            return;
        end

        % Check if hydrophone amp checkbox is checked
        if ~app.CheckBox_hydrophone_amp.Value
            uialert(app.UIFigure, 'Ensure that the hydrophone amplifier is set to 100 mV/Pa and check the box.', 'Hydrophone Amp Not Checked');
            return;
        end

        % Initialize ex structure
        app.ex = setup_ex();
        app.ex.info.animal.subject_ID = app.EditField_ID.Value;
        app.ex.info.animal.sex = app.EditField_sex.Value;
        app.ex.info.animal.health = app.DropDown_health.Value;
        app.ex.info.stimulus.frequency_hz = app.EditField_frequency.Value;
        app.ex.info.stimulus.amplitude_spl = app.EditField_amplitude.Value;
        app.ex.info.stimulus.starting_amp_spl = app.EditField_amplitude.Value;
        app.ex.info.adaptive.response_feature = app.DropDown_response_feature.Value;
        app.ex.info.adaptive.trials_per_block = app.EditField_N_trials_per_block.Value;
        app.ex.info.adaptive.max_trials = app.EditField_max_trials.Value;
        app.ex.info.animal.filename = [app.ex.info.animal.species_name '_' ...
            num2str(app.ex.info.animal.subject_ID) '_' ...
            num2str(app.ex.info.stimulus.frequency_hz) '_Hz_' ...
            num2str(app.ex.info.stimulus.amplitude_spl) '_SPL_' ...
            app.ex.info.experiment.exp_date '_' ...
            app.ex.info.experiment.exp_time_start];

        app.InitializeButton.Text = 'Redo initialization';
        app.CalibratestimulusButton.Enable = 'on';

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_hydrophone = uiaxes(app.UIFigure);
            title(app.UIAxes_hydrophone, 'Hydrophone')
            xlabel(app.UIAxes_hydrophone, 'X')
            ylabel(app.UIAxes_hydrophone, 'Y')
            zlabel(app.UIAxes_hydrophone, 'Z')
            app.UIAxes_ch1 = uiaxes(app.UIFigure);
            title(app.UIAxes_ch1, 'Channel 1')
            xlabel(app.UIAxes_ch1, 'X')
            ylabel(app.UIAxes_ch1, 'Y')
            zlabel(app.UIAxes_ch1, 'Z')
            app.UIAxes_ch2 = uiaxes(app.UIFigure);
            title(app.UIAxes_ch2, 'Channel 2')
            xlabel(app.UIAxes_ch2, 'X')
            ylabel(app.UIAxes_ch2, 'Y')
            zlabel(app.UIAxes_ch2, 'Z')
            app.UIAxes_ch3 = uiaxes(app.UIFigure);
            title(app.UIAxes_ch3, 'Channel 4')
            xlabel(app.UIAxes_ch3, 'X')
            ylabel(app.UIAxes_ch3, 'Y')
            zlabel(app.UIAxes_ch3, 'Z')
            app.UIAxes_ch4 = uiaxes(app.UIFigure);
            title(app.UIAxes_ch4, 'Channel 5')
            xlabel(app.UIAxes_ch4, 'X')
            ylabel(app.UIAxes_ch4, 'Y')
            zlabel(app.UIAxes_ch4, 'Z')
            app.UIAxes_boot = uiaxes(app.UIFigure);
            title(app.UIAxes_boot, 'Bootstrap Distribution')
            xlabel(app.UIAxes_boot, 'X')
            ylabel(app.UIAxes_boot, 'Y')
            zlabel(app.UIAxes_boot, 'Z')
            app.UIAxes_model = uiaxes(app.UIFigure);
            title(app.UIAxes_model, 'Model')
            xlabel(app.UIAxes_model, 'X')
            ylabel(app.UIAxes_model, 'Y')
            zlabel(app.UIAxes_model, 'Z')
            app.SubjectLabel = uilabel(app.UIFigure);
            app.SubjectLabel.Text = 'Subject';
            app.IDLabel = uilabel(app.UIFigure);
            app.IDLabel.Text = 'ID #:';
            app.SexLabel = uilabel(app.UIFigure);
            app.SexLabel.Text = 'Sex:';
            app.HealthLabel = uilabel(app.UIFigure);
            app.HealthLabel.Text = 'Health:';
            app.StimulusLabel = uilabel(app.UIFigure);
            app.StimulusLabel.Text = 'Stimulus';
            app.FrequencyHzLabel = uilabel(app.UIFigure);
            app.FrequencyHzLabel.Text = 'Frequency (Hz):';
            app.StartingamplitudedBSPLLabel = uilabel(app.UIFigure);
            app.StartingamplitudedBSPLLabel.Text = 'Starting amplitude (dB SPL):';
            app.ResponsefeatureLabel = uilabel(app.UIFigure);
            app.ResponsefeatureLabel.Text = 'Response feature:';
            app.NtrialsblockLabel_2 = uilabel(app.UIFigure);
            app.NtrialsblockLabel_2.Text = 'N trials/block:';
            app.MaximumtrialsLabel_2 = uilabel(app.UIFigure);
            app.MaximumtrialsLabel_2.Text = 'Maximum trials:';
            app.ExperimentLabel = uilabel(app.UIFigure);
            app.ExperimentLabel.Text = 'Experiment';
            app.EditField_ID = uieditfield(app.UIFigure, 'numeric');
            app.EditField_ID.AllowEmpty = 'on';
            app.EditField_ID.Value = [];
            app.EditField_frequency = uieditfield(app.UIFigure, 'numeric');
            app.EditField_frequency.AllowEmpty = 'on';
            app.EditField_frequency.Value = [];
            app.EditField_amplitude = uieditfield(app.UIFigure, 'numeric');
            app.EditField_amplitude.AllowEmpty = 'on';
            app.EditField_amplitude.Value = 140;
            app.EditField_N_trials_per_block = uieditfield(app.UIFigure, 'numeric');
            app.EditField_N_trials_per_block.Value = 20;
            app.EditField_max_trials = uieditfield(app.UIFigure, 'numeric');
            app.EditField_max_trials.Value = 1000;
            app.logo = uiimage(app.UIFigure);
            app.logo.ImageSource = 'adapt_aep_logo.png';
            app.EditField_sex = uieditfield(app.UIFigure, 'text');
            app.CalibratestimulusButton = uibutton(app.UIFigure, 'push');
            app.CalibratestimulusButton.ButtonPushedFcn = createCallbackFcn(app, @start_calibration, true);
            app.CalibratestimulusButton.Enable = 'off';
            app.CalibratestimulusButton.Text = 'Calibrate stimulus';
            app.StartexperimentButton = uibutton(app.UIFigure, 'push');
            app.StartexperimentButton.ButtonPushedFcn = createCallbackFcn(app, @start_exp, true);
            app.StartexperimentButton.Enable = 'off';
            app.StartexperimentButton.Text = 'Start experiment';
            app.DropDown_response_feature = uidropdown(app.UIFigure);
            app.DropDown_response_feature.Items = {'Double frequency'};
            app.DropDown_response_feature.Value = 'Double frequency';
            app.MonitorLabel = uilabel(app.UIFigure);
            app.MonitorLabel.Text = 'Monitor';
            app.AnalysisLabel = uilabel(app.UIFigure);
            app.AnalysisLabel.Text = 'Analysis';
            app.DropDown_health = uidropdown(app.UIFigure);
            app.DropDown_health.Items = {'Good', 'Fair', 'Poor'};
            app.DropDown_health.Value = 'Good';
            app.CheckBox_hydrophone_amp = uicheckbox(app.UIFigure);
            app.CheckBox_hydrophone_amp.Text = '';
            app.Hydrophoneamplifiersetto100mVPaLabel = uilabel(app.UIFigure);
            app.Hydrophoneamplifiersetto100mVPaLabel.Text = 'Hydrophone amplifier set to 100 mV/Pa';
            app.InitializeButton = uibutton(app.UIFigure, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @start_init, true);
            app.InitializeButton.Text = 'Initialize';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = adapt_aep_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end