classdef adapt_aep_cor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
    end


    properties (Access = public)
        ex % Experiment structure
        PauseFlag logical = false
    end

    methods (Access = private)
        function clearAllAxes(app)
            cla(app.UIAxes_hydrophone);
            title(app.UIAxes_hydrophone,'Hydrophone')
            cla(app.UIAxes_ch1);
            cla(app.UIAxes_ch2);
            cla(app.UIAxes_ch3);
            cla(app.UIAxes_ch4);
            cla(app.UIAxes_boot);
            cla(app.UIAxes_model);
            title(app.UIAxes_model,'Model')
            cla(app.UIAxes_model_3);
            title(app.UIAxes_model_3,'Model')
            cla(app.UIAxes_diff_fft);
            cla(app.UIAxes_thresh_est);
            cla(app.UIAxes_slope_est);
            cla(app.UIAxes_gate);
            cla(app.UIAxes_tank_noise_floor);
            cla(app.UIAxes_signal_SNR);

            cla(app.UIAxes_funfetti);
            delete(allchild(app.UIAxes_funfetti));
            

            cla(app.UIAxes_live_fft);
            delete(allchild(app.UIAxes_live_fft));
            cla(app.UIAxes_live_fft);
            delete(allchild(app.UIAxes_live_fft));
            delete(app.UIAxes_live_fft.Legend);

            title(app.UIAxes_live_fft, 'Live FFT Monitor')
            xlabel(app.UIAxes_live_fft, 'Frequency (Hz)')
            ylabel(app.UIAxes_live_fft, 'Magnitude (\muV)')
            zlabel(app.UIAxes_live_fft, 'Z')
            app.UIAxes_live_fft.Box = 'on';
            app.UIAxes_live_fft.Visible = 'off';
            app.UIAxes_live_fft.Position = [740 533 740 282];

            title(app.UIAxes_funfetti, '2f Response Tracking')
            xlabel(app.UIAxes_funfetti, 'Iteration')
            ylabel(app.UIAxes_funfetti, '2f Magnitude (\muV)')
            zlabel(app.UIAxes_funfetti, 'Z')
            app.UIAxes_funfetti.Box = 'on';
            app.UIAxes_funfetti.Visible = 'off';
            app.UIAxes_funfetti.Position = [740 9 740 492];

        end

        function setAxesVisibility(app)
            mode = app.DropDown_test_mode.Value;
            on   = ismember(mode, {'Timed','Static trial count'});
            adp  = strcmp(mode, 'Adaptive');
            app.UIAxes_funfetti.Visible   = on;
            app.UIAxes_live_fft.Visible   = on;
            app.UIAxes_model_3.Visible    = adp;
            app.UIAxes_gate.Visible       = adp;
            app.UIAxes_slope_est.Visible  = adp;
            app.UIAxes_thresh_est.Visible = adp;
            app.UIAxes_diff_fft.Visible   = adp;
            app.UIAxes_model.Visible      = adp;
            app.UIAxes_boot.Visible       = adp;
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function start_exp(app, event)
            fprintf('\nStarting experiment...\n')
            [y, Fs] = audioread('button_press.mp3');
            sound(y, Fs)

            % Clear GUI
            clearAllAxes(app)
            setAxesVisibility(app)
            app.Label_current_amp.Text = 'X';
            app.Label_number_trials_presented.Text = 'X';
            app.Label_rejection_rate.Text = 'X';
            app.Label_grand_total.Text = 'X';
            app.Label_time_elapsed.Text = 'X';
            app.Label_RMS_ratio.Text = 'X';
            app.Label_tank_noise_floor.Text = 'X';
            app.Label_signal_snr.Text = 'X';

            app.CalibrateButton.Enable = 'off';
            app.InitializeButton.Enable = 'off';
            app.TempButton.Enable = 'off';
            app.HealthButton.Enable = 'off';
            app.PauseButton.Enable = 'on';

            % Rip it
            run_adapt_aep(app);
            fprintf('\nFinished testing at current frequency.')

            % Reset all controls to initial state
            app.EditField_ID.Enable = 'on';
            app.EditField_frequency.Enable = 'on';
            app.EditField_frequency.Value = [];
            app.EditField_max_trials.Enable = 'on';
            app.DropDown_response_feature.Enable = 'on';
            app.DropDown_response_feature.Value = 'Double frequency';
            app.DropDown_test_mode.Enable = 'on';
            app.DropDown_test_mode.Value = 'Select test mode';
            app.DropDown_test_tag.Enable = 'on';
            app.DropDown_test_tag.Value = 'Select test tag';
            app.DropDown_sex.Enable = 'on';
            app.DropDown_tone_burst.Enable = 'on';
            app.DropDown_tone_burst.Value = 'Tone burst';

            app.InitializeButton.Enable = 'on';
            app.CalibrateButton.Enable = 'off';
            app.HealthButton.Enable = 'off';
            app.TempButton.Enable = 'off';
            app.StartButton.Enable = 'off';
            app.PauseButton.Enable = 'off';

            app.Label_current_amp.Text = 'X';
            app.Label_number_trials_presented.Text = 'X';
            app.Label_rejection_rate.Text = 'X';
            app.Label_grand_total.Text = 'X';
            app.Label_time_elapsed.Text = 'X';
            app.Label_RMS_ratio.Text = 'X';
            app.Label_tank_noise_floor.Text = 'X';
            app.Label_signal_snr.Text = 'X';
            app.EditField_max_trials.Visible = "off";

            app.EditField_max_trials.Visible   = 'off';
            app.EditField_timer.Visible        = 'off';
            app.TimerdurationLabel.Visible     = 'off';
            app.minutesLabel.Visible           = 'off';
            app.MaximumtrialsLabel_2.Visible   = 'off';
            app.peramplitudeLabel.Visible      = 'off';

            app.SelectTestModetopopulateLabel.Visible = "on";

            app.ex = [];
            app.PauseFlag = false;
            clearAllAxes(app);
            setAxesVisibility(app)

            [y, Fs] = audioread('finished_exp.mp3');
            sound(y, Fs)
        end

        % Callback function
        function start_calibration(app, event)
            fprintf('\nStarting calibration...\n')
            [y, Fs] = audioread('button_press.mp3');
            sound(y, Fs)

            % Check if ex has been initialized
            if isempty(app.ex) || ~isfield(app.ex, 'info')
                uialert(app.UIFigure, 'Please initialize experiment first.', 'Not Initialized');
                return;
            end

            % Create the calibration app
            calibration_app = calibrate_stimulus();

            % Initialize it with ex
            calibration_app.initializeWithEx(app.ex);

            % Automatic button push
            t = timer('StartDelay', 0.1, 'TimerFcn', @(varargin) calibration_app.runCalibrate());
            start(t);
            uiwait(calibration_app.UIFigure);  % now listening before button fires
            delete(t);

            % Get the updated ex back
            app.ex = calibration_app.ex;

            % Clean up
            delete(calibration_app)

            app.CalibrateButton.Enable = 'on';
            app.HealthButton.Enable = 'on';
            app.StartButton.Enable = "on";

            [y, Fs] = audioread('completed.mp3');
            sound(y, Fs)

            fprintf('\nFinished calibration\n')
        end

        % Callback function
        function start_init(app, event)
            fprintf('\nStarting initalization\n')

            % Initialize ex structure
            addpath(genpath('C:\Users\AEP\Desktop\adapt_aep\src\matlab'))
            app.ex = setup_ex();
            [y, Fs] = audioread('button_press.mp3');
            sound(y, Fs)

            % Check if all required fields are filled
            if isempty(app.EditField_ID.Value) || strcmp(app.DropDown_sex.Value,'X') || ...
                    isnan(app.EditField_frequency.Value) || strcmp(app.DropDown_response_feature.Value, 'Select response feature') || isnan(app.EditField_max_trials.Value) || ...
                    strcmp(app.DropDown_test_mode.Value,'Select test mode') || strcmp(app.DropDown_test_tag.Value, 'Select test tag')

                uialert(app.UIFigure, 'Make sure all fields are filled out prior to continuing.', 'Missing Fields');
                return;
            end

            % Setup info
            app.ex = setup_info(app.ex); % User input of experiment parameters

            % Extract GUI Values
            app.ex.info.animal.subject_ID = app.EditField_ID.Value;
            app.ex.info.animal.sex = app.DropDown_sex.Value;
            app.ex.info.stimulus.frequency_hz = app.EditField_frequency.Value;
            app.ex.info.stimulus.type = app.DropDown_tone_burst.Value;
            app.ex.info.adaptive.response_feature = app.DropDown_response_feature.Value;
            app.ex.info.adaptive.max_trials = app.EditField_max_trials.Value;
            app.ex.info.experiment.timer_dur_min = app.EditField_timer.Value;
            app.ex.info.experiment.exp_type = app.DropDown_test_mode.Value;
            app.ex.info.experiment.test_tag = app.DropDown_test_tag.Value;
            app.ex.info.animal.filename_root = sprintf('%s_%d_%gHz', ...
                app.ex.info.animal.species_name, ...
                app.ex.info.animal.subject_ID, ...
                app.ex.info.stimulus.frequency_hz);

            % Set up sets of ex
            app.ex = setup_block(app.ex); % Per amplitude meta/data
            app.ex = setup_analysis(app.ex); % Analysis deta/data
            app.ex = setup_model(app.ex);

            app.ex = setup_hardware(app.ex);

            %% Generate Stimulus Template
            app.ex = select_experiment_stim_params(app.ex);
            app.ex = make_stimulus_template(app.ex);

            % GUI Buttons
            app.InitializeButton.Enable = 'off';
            app.HealthButton.Enable = 'on';
            app.EditField_ID.Enable = 'off';
            app.EditField_frequency.Enable = 'off';
            app.EditField_max_trials.Enable = 'off';
            app.DropDown_response_feature.Enable = 'off';
            app.DropDown_test_mode.Enable = 'off';
            app.DropDown_test_tag.Enable = 'off';
            app.DropDown_sex.Enable = 'on';
            app.DropDown_sex.Enable = "off";
            app.DropDown_tone_burst.Enable = "off";
            app.DropDown_test_mode.Enable = "off";

            app.EditField_max_trials.Enable   = 'off';
            app.EditField_max_trials.Editable = 'off';

            app.EditField_timer.Editable = 'off';
            app.EditField_timer.Enable   = 'off';

            [y, Fs] = audioread('completed.mp3');
            sound(y, Fs)

            fprintf('\nInitialization complete\n')

        end

        % Callback function
        function HealthButtonPushed(app, event)
            fprintf('\nStarting health check...\n')
            % app.HealthButton.Enable = 'off';
            app.ex = check_health(app.ex,app, 1);

            app.HealthButton.Enable = 'on';
            app.TempButton.Enable = 'off';
            app.CalibrateButton.Enable = 'on';


            [y, Fs] = audioread('completed.mp3');
            sound(y, Fs)
            fprintf('\nFinished health check\n')

        end

        % Callback function
        function TempButtonPushed(app, event)
            fprintf('\nStarting temperature check...\n')
            [y, Fs] = audioread('button_press.mp3');
            sound(y, Fs)
            app.TempButton.Enable = 'off';
            app.StartButton.Enable = 'on';
            [y, Fs] = audioread('completed.mp3');
            sound(y, Fs)
            fprintf('\nFinished temperature check\n')
        end

        % Callback function
        function PauseButtonPushed(app, event)
            [y, Fs] = audioread('button_press.mp3');
            sound(y, Fs)
            app.PauseFlag = true;
            [y, Fs] = audioread('completed.mp3');
            sound(y, Fs)
        end

        % Callback function
        function DropDown_test_modeValueChanged(app, event)
            value = app.DropDown_test_mode.Value;
            app.SelectTestModetopopulateLabel.Visible = "off";
            % Determine which mode we're in
            isTimed     = strcmp(value, 'Timed');
            isStatic    = strcmp(value, 'Static trial count');
            isAdaptive  = strcmp(value, 'Adaptive');
            showTrials  = isStatic || isAdaptive;   % both use max trials

            % --- Timer-related controls (only for "Timed") ---
            app.EditField_timer.Visible    = isTimed;
            app.TimerdurationLabel.Visible = isTimed;
            app.minutesLabel.Visible       = isTimed;

            % --- Max-trials-related controls (Static + Adaptive) ---
            app.EditField_max_trials.Visible   = showTrials;
            app.MaximumtrialsLabel_2.Visible   = showTrials;
            app.peramplitudeLabel.Visible      = showTrials;

            % Set defaults / enable state per mode
            if isTimed
                app.EditField_timer.Editable = 'on';
                app.EditField_timer.Enable   = 'on';
                app.EditField_timer.Value    = 30;
                app.EditField_max_trials.Value = 5000;
            elseif isStatic
                app.EditField_max_trials.Enable   = 'on';
                app.EditField_max_trials.Editable = 'on';
                app.EditField_max_trials.Value    = 1024;
                app.EditField_timer.Value         = 30;
            elseif isAdaptive
                app.EditField_max_trials.Enable   = 'on';
                app.EditField_max_trials.Editable = 'on';
                app.EditField_max_trials.Value    = 256;
                app.EditField_timer.Value         = 30;
            end

            % Toggle plot axes visibility (unchanged)
            isTimedOrStatic = isTimed || isStatic;
            app.UIAxes_funfetti.Visible  = isTimedOrStatic;
            app.UIAxes_live_fft.Visible  = isTimedOrStatic;
            app.UIAxes_model_3.Visible   = isAdaptive;
            app.UIAxes_gate.Visible      = isAdaptive;
            app.UIAxes_slope_est.Visible = isAdaptive;
            app.UIAxes_thresh_est.Visible= isAdaptive;
            app.UIAxes_diff_fft.Visible  = isAdaptive;
            app.UIAxes_model.Visible     = isAdaptive;
            app.UIAxes_boot.Visible      = isAdaptive;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = adapt_aep_cor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end