classdef calibrate_stimulus < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        label_snr                 matlab.ui.control.Label
        SignalSNRdBLabel          matlab.ui.control.Label
        button_calibrate          matlab.ui.control.Button
        label_corr_level          matlab.ui.control.Label
        label_corr_factor         matlab.ui.control.Label
        label_uncorr_level        matlab.ui.control.Label
        label_target_amp          matlab.ui.control.Label
        label_stim_freq           matlab.ui.control.Label
        CorrectedleveldBLabel     matlab.ui.control.Label
        CorrectionfactordBLabel   matlab.ui.control.Label
        UncorrectedleveldBLabel   matlab.ui.control.Label
        TargetampdBLabel          matlab.ui.control.Label
        FrequencyHzLabel          matlab.ui.control.Label
        StimulusCalibrationLabel  matlab.ui.control.Label
        ax_hydrophone_spectra     matlab.ui.control.UIAxes
        ax_hydrophone             matlab.ui.control.UIAxes
    end


    properties (Access = public)
        ex % Experiment structure
    end

    methods (Access = public)

        % Initialize app with experiment structure
        function initializeWithEx(app, ex)
            app.ex = ex;
        end

        function runCalibrate(app)
            app.button_calibrateButtonPushed([]);
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Button pushed function: button_calibrate
        function button_calibrateButtonPushed(app, event)
            app.button_calibrate.Enable = "off";
            try
                for ifreq = 1:length(app.ex.info.stimulus)
                    % Update GUI Label
                    app.label_stim_freq.Text = string(app.ex.info.stimulus(ifreq).frequency_hz);
                    app.label_target_amp.Text = string(app.ex.info.calibration(ifreq).target_amp_spl);
                    
                    % Run calibrate stimulus
                    [app.ex, cal] = run_calibrate_stimulus(app, app.ex, ...
                        app.ex.info.recording, ...
                        app.ex.info.stimulus(ifreq), ...
                        app.ex.info.calibration(ifreq));

                    % Save back calibration info
                    app.ex.info.calibration(ifreq) = cal;
                    
                    if ~app.ex.info.calibration(ifreq).check_passed
                        app.button_calibrate.Enable = "on";
                        app.button_calibrate.Text = "Calibrate";
                        app.button_calibrate.BackgroundColor = [1 0 0];
                        drawnow;
                    else
                        pause(3);
                        uiresume(app.UIFigure)
                    end
                end
            catch ME
                disp(ME.getReport)
                app.button_calibrate.Enable = "on";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ax_hydrophone = uiaxes(app.UIFigure);
            title(app.ax_hydrophone, 'Hydrophone Signal')
            xlabel(app.ax_hydrophone, 'Time')
            ylabel(app.ax_hydrophone, 'Amplitude (mV)')
            zlabel(app.ax_hydrophone, 'Z')
            app.ax_hydrophone_spectra = uiaxes(app.UIFigure);
            title(app.ax_hydrophone_spectra, 'Hydrophone Spectra')
            xlabel(app.ax_hydrophone_spectra, 'Frequency')
            ylabel(app.ax_hydrophone_spectra, 'Amplitude (dB)')
            zlabel(app.ax_hydrophone_spectra, 'Z')
            app.StimulusCalibrationLabel = uilabel(app.UIFigure);
            app.StimulusCalibrationLabel.Text = 'Stimulus Calibration';
            app.FrequencyHzLabel = uilabel(app.UIFigure);
            app.FrequencyHzLabel.Text = 'Frequency (Hz):';
            app.TargetampdBLabel = uilabel(app.UIFigure);
            app.TargetampdBLabel.Text = 'Target amp. (dB):';
            app.UncorrectedleveldBLabel = uilabel(app.UIFigure);
            app.UncorrectedleveldBLabel.Text = 'Uncorrected level (dB):';
            app.CorrectionfactordBLabel = uilabel(app.UIFigure);
            app.CorrectionfactordBLabel.Text = 'Correction factor (dB):';
            app.CorrectedleveldBLabel = uilabel(app.UIFigure);
            app.CorrectedleveldBLabel.Text = 'Corrected level (dB):';
            app.label_stim_freq = uilabel(app.UIFigure);
            app.label_stim_freq.Text = 'X';
            app.label_target_amp = uilabel(app.UIFigure);
            app.label_target_amp.Text = 'X';
            app.label_uncorr_level = uilabel(app.UIFigure);
            app.label_uncorr_level.Text = 'X';
            app.label_corr_factor = uilabel(app.UIFigure);
            app.label_corr_factor.Text = 'X';
            app.label_corr_level = uilabel(app.UIFigure);
            app.label_corr_level.Text = 'X';
            app.button_calibrate = uibutton(app.UIFigure, 'push');
            app.button_calibrate.ButtonPushedFcn = createCallbackFcn(app, @button_calibrateButtonPushed, true);
            app.button_calibrate.Enable = 'off';
            app.button_calibrate.Text = 'Calibrate';
            app.SignalSNRdBLabel = uilabel(app.UIFigure);
            app.SignalSNRdBLabel.Text = 'Signal SNR (dB) :';
            app.label_snr = uilabel(app.UIFigure);
            app.label_snr.Text = 'X';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = calibrate_stimulus

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end